classdef SSVEP_session < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_12                       matlab.ui.container.Panel
        PalmSpinner                    matlab.ui.control.Spinner
        PalmSpinnerLabel               matlab.ui.control.Label
        FistSpinner                    matlab.ui.control.Spinner
        FistSpinnerLabel               matlab.ui.control.Label
        HZLabel_2                      matlab.ui.control.Label
        HZLabel                        matlab.ui.control.Label
        SSVEPLabel                     matlab.ui.control.Label
        ResultPanel_3                  matlab.ui.container.Panel
        PalmResultTextArea             matlab.ui.control.TextArea
        PalmResultTextAreaLabel        matlab.ui.control.Label
        AccuracyEditField_Palm         matlab.ui.control.NumericEditField
        AccuracyEditField_3Label       matlab.ui.control.Label
        ResultPanel_2                  matlab.ui.container.Panel
        FistResultTextArea             matlab.ui.control.TextArea
        FistResultTextAreaLabel        matlab.ui.control.Label
        AccuracyEditField_Fist         matlab.ui.control.NumericEditField
        AccuracyEditField_2Label       matlab.ui.control.Label
        RepeatCountEditField           matlab.ui.control.NumericEditField
        TabGroup3                      matlab.ui.container.TabGroup
        TrainTab                       matlab.ui.container.Tab
        TrainingsessionPanel           matlab.ui.container.Panel
        SetpathButton                  matlab.ui.control.Button
        TimePanel                      matlab.ui.container.Panel
        SecondEditField                matlab.ui.control.NumericEditField
        SecondLabel                    matlab.ui.control.Label
        MinuteEditField                matlab.ui.control.NumericEditField
        MinuteLabel                    matlab.ui.control.Label
        TrainingsessionTabGroup        matlab.ui.container.TabGroup
        SignalacquisitionprotocolsettingDisplayuiaxesTab  matlab.ui.container.Tab
        Panel_10                       matlab.ui.container.Panel
        PalmsoundCheckBox              matlab.ui.control.CheckBox
        RestingsoundCheckBox           matlab.ui.control.CheckBox
        FistsoundCheckBox              matlab.ui.control.CheckBox
        Panel_5                        matlab.ui.container.Panel
        RepeatCountLabel               matlab.ui.control.Label
        RepeatCountSpinner             matlab.ui.control.Spinner
        Panel_4                        matlab.ui.container.Panel
        RestingMeasurementTimeLabel    matlab.ui.control.Label
        RestingMeasurementTimeSpinner  matlab.ui.control.Spinner
        Panel_3                        matlab.ui.container.Panel
        MI_MeasurementTimeLabel        matlab.ui.control.Label
        MI_MeasurementTimeSpinner      matlab.ui.control.Spinner
        StopButton                     matlab.ui.control.Button
        TrainingRecordButton           matlab.ui.control.Button
        TestTab                        matlab.ui.container.Tab
        TestsessionPanel               matlab.ui.container.Panel
        SetpathButton_2                matlab.ui.control.Button
        StopButton_2                   matlab.ui.control.Button
        RecordButton_2                 matlab.ui.control.Button
        TimePanel_2                    matlab.ui.container.Panel
        SecondEditField_2              matlab.ui.control.NumericEditField
        SecondLabel_2                  matlab.ui.control.Label
        MinuteEditField_2              matlab.ui.control.NumericEditField
        MinuteLabel_2                  matlab.ui.control.Label
        TestsessionTabGroup            matlab.ui.container.TabGroup
        SignalacquisitionprotocolsettingDisplayuiaxesTab_2  matlab.ui.container.Tab
        Panel_11                       matlab.ui.container.Panel
        PalmsoundCheckBox_2            matlab.ui.control.CheckBox
        RestingsoundCheckBox_2         matlab.ui.control.CheckBox
        FistsoundCheckBox_2            matlab.ui.control.CheckBox
        Panel_9                        matlab.ui.container.Panel
        TargetsizeLabel_5              matlab.ui.control.Label
        RepeatCountSpinner_2           matlab.ui.control.Spinner
        Panel_8                        matlab.ui.container.Panel
        TargetsizeLabel_4              matlab.ui.control.Label
        RestingMeasurementTimeSpinner_2  matlab.ui.control.Spinner
        Panel_7                        matlab.ui.container.Panel
        MeasurementtimeofmotorimagerySLabel_2  matlab.ui.control.Label
        MI_MeasurementTimeSpinner_2    matlab.ui.control.Spinner
        PalmButton                     matlab.ui.control.Button
        PalmSoundImage                 matlab.ui.control.Image
        RestingButton                  matlab.ui.control.Button
        FIstButton                     matlab.ui.control.Button
        RestingSoundImage              matlab.ui.control.Image
        FistSoundImage                 matlab.ui.control.Image
        ResultPanel                    matlab.ui.container.Panel
        TotalResultTextArea            matlab.ui.control.TextArea
        TotalResultTextAreaLabel       matlab.ui.control.Label
        AccuracyEditField              matlab.ui.control.NumericEditField
        AccuracyLabel                  matlab.ui.control.Label
        SignalpreprocessingTraningclassificationmodelPanel  matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        PreprocessingTab               matlab.ui.container.Tab
        L_HLabel_3                     matlab.ui.control.Label
        LowcutoffEditField_3           matlab.ui.control.NumericEditField
        LowcutoffEditField_3Label      matlab.ui.control.Label
        HighcutoffEditField_3          matlab.ui.control.NumericEditField
        HighcutoffEditField_3Label     matlab.ui.control.Label
        BandPassFilterLabel            matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        CARCheckBox                    matlab.ui.control.CheckBox
        CARLabel                       matlab.ui.control.Label
        DownsamplingSpinner            matlab.ui.control.Spinner
        DownsamplingSpinnerLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        WindowrangeTab                 matlab.ui.container.Tab
        WindowrangeSpinner_2           matlab.ui.control.Spinner
        WindowrangeSpinner             matlab.ui.control.Spinner
        WindowLabel                    matlab.ui.control.Label
        SegmentTab                     matlab.ui.container.Tab
        WindowSplitSpinner             matlab.ui.control.Spinner
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        FeatureselectionTab            matlab.ui.container.Tab
        ERDERSButton                   matlab.ui.control.StateButton
        BandpowerButton                matlab.ui.control.StateButton
        OverlabSpinner                 matlab.ui.control.Spinner
        RangeSpinner                   matlab.ui.control.Spinner
        OverlapCheckBox                matlab.ui.control.CheckBox
        RangeCheckBox                  matlab.ui.control.CheckBox
        LFBEditField_10                matlab.ui.control.NumericEditField
        FBLabel_10                     matlab.ui.control.Label
        HFBEditField_10                matlab.ui.control.NumericEditField
        LFBEditField_9                 matlab.ui.control.NumericEditField
        FBLabel_9                      matlab.ui.control.Label
        HFBEditField_9                 matlab.ui.control.NumericEditField
        LFBEditField_8                 matlab.ui.control.NumericEditField
        FBLabel_8                      matlab.ui.control.Label
        HFBEditField_8                 matlab.ui.control.NumericEditField
        LFBEditField_7                 matlab.ui.control.NumericEditField
        FBLabel_7                      matlab.ui.control.Label
        HFBEditField_7                 matlab.ui.control.NumericEditField
        LFBEditField_6                 matlab.ui.control.NumericEditField
        FBLabel_6                      matlab.ui.control.Label
        HFBEditField_6                 matlab.ui.control.NumericEditField
        LFBEditField_1                 matlab.ui.control.NumericEditField
        HFBEditField_1                 matlab.ui.control.NumericEditField
        FBLabel_1                      matlab.ui.control.Label
        LFBEditField_5                 matlab.ui.control.NumericEditField
        FBLabel_5                      matlab.ui.control.Label
        HFBEditField_5                 matlab.ui.control.NumericEditField
        LFBEditField_4                 matlab.ui.control.NumericEditField
        FBLabel_4                      matlab.ui.control.Label
        HFBEditField_4                 matlab.ui.control.NumericEditField
        LFBEditField_3                 matlab.ui.control.NumericEditField
        FBLabel_3                      matlab.ui.control.Label
        HFBEditField_3                 matlab.ui.control.NumericEditField
        LFBEditField_2                 matlab.ui.control.NumericEditField
        FBLabel_2                      matlab.ui.control.Label
        HFBEditField_2                 matlab.ui.control.NumericEditField
        FilterbankSpinner              matlab.ui.control.Spinner
        FilterbankSpinnerLabel         matlab.ui.control.Label
        LoaddataLabel                  matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        ChannelNode                    matlab.ui.container.TreeNode
        F7Node                         matlab.ui.container.TreeNode
        FP1Node                        matlab.ui.container.TreeNode
        FP2Node                        matlab.ui.container.TreeNode
        F8Node                         matlab.ui.container.TreeNode
        F3Node                         matlab.ui.container.TreeNode
        FZNode                         matlab.ui.container.TreeNode
        F4Node                         matlab.ui.container.TreeNode
        C3Node                         matlab.ui.container.TreeNode
        CZNode                         matlab.ui.container.TreeNode
        P8Node                         matlab.ui.container.TreeNode
        P7Node                         matlab.ui.container.TreeNode
        PZNode                         matlab.ui.container.TreeNode
        P4Node                         matlab.ui.container.TreeNode
        T3Node                         matlab.ui.container.TreeNode
        P3Node                         matlab.ui.container.TreeNode
        O1Node                         matlab.ui.container.TreeNode
        O2Node                         matlab.ui.container.TreeNode
        C4Node                         matlab.ui.container.TreeNode
        T4Node                         matlab.ui.container.TreeNode
        TraningmodelButton             matlab.ui.control.Button
        LocationFileLabel              matlab.ui.control.Label
        LoadtraningdataButton          matlab.ui.control.Button
        ProtocolUIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        stopFlag = false

        locations
        sample_rate = 500

        load_data

        sound_fs
        sound_f1
        sound_x1
        sound_f2
        sound_x2
        sound_f3
        sound_x3

        filter_bank

        checkedIndices
        SVMModel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Trigger and resting sound
            sound_duration = 0.2; % 소리 재생 시간 (초)
            app.sound_fs = 5000; % 샘플링 주파수 (Hz)
            sound_t = linspace(0, sound_duration, sound_duration*app.sound_fs); % 시간 벡터 생성
            app.sound_f1 = 440; % 소리 주파수 (Hz)
            app.sound_x1 = sin(2*pi*app.sound_f1*sound_t); % 사인파 생성
            app.sound_f2 = 220; % 소리 주파수 (Hz)
            app.sound_x2 = sin(2*pi*app.sound_f2*sound_t); % 사인파 생성
            app.sound_f3 = 110; % 소리 주파수 (Hz)
            app.sound_x3 = sin(2*pi*app.sound_f3*sound_t); % 사인파 생성

        end

        % Button pushed function: SetpathButton
        function SetpathButtonPushed(app, event)
            try
                selectedFolder = uigetdir('C:\', 'Select a file');
                addpath(genpath(selectedFolder));
            catch
            end

        end

        % Button pushed function: SetpathButton_2
        function SetpathButton_2Pushed(app, event)
            try
                selectedFolder = uigetdir('C:\', 'Select a file');
                addpath(genpath(selectedFolder));
            catch
            end

        end

        % Button pushed function: TrainingRecordButton
        function TrainingRecordButtonPushed(app, event)
            %%% Parameters and variables setting

            preparation_time = 0;
            time = (2*app.MI_MeasurementTimeSpinner.Value + 2*app.RestingMeasurementTimeSpinner.Value)*app.RepeatCountSpinner.Value + preparation_time; % 총 시간

            % 시간 표시 업데이트
            if time >= 60
                app.MinuteEditField.Value = fix(time / 60);
                app.SecondEditField.Value = rem(time, 60);
            else
                app.MinuteEditField.Value = 0;
                app.SecondEditField.Value = time;
            end

            % 초기화
            minute = app.MinuteEditField.Value;
            second = app.SecondEditField.Value;


            % load channel locations
            app.locations = load('CGX-10-20-Cap19_2.mat');
            app.locations = app.locations.locations;

            complete_record = "yes";

            rawdata = []; % channel X data (matrix)
            timesum = []; % 총 측정시간이 입력될 백터


            resting_i = 0;
            Fist_i = 0;
            resting2_i = 0;
            Palm_i = 0;


            EEG.setname = 'csvfile';
            EEG.filename = '';
            EEG.filepath = '';
            EEG.subject = '';
            EEG.nbchan = size(app.locations,1);
            EEG.trials = 1;
            EEG.pnts = [];
            EEG.srate = app.sample_rate;
            EEG.xmin = 0;
            EEG.xmax = [];
            EEG.data = [];
            EEG.icaact = [];
            EEG.icawinv = [];
            EEG.icasphere = [];
            EEG.icaweights = [];
            EEG.icachansind = [];
            location_struct = table2struct(app.locations);
            EEG.chanlocs = location_struct;
            EEG.urchanlocs = [];
            EEG.chaninfo = [];
            EEG.event = [];
            EEG.urevent = [];
            EEG.epoch = [];
            EEG.reject = [];
            EEG.specdata = [];
            EEG.specicaact = [];
            EEG.etc = [];

            fft_i = 1;

            resting_events = [];
            Fist_events = [];
            resting_events_2 = [];
            Palm_events = [];
            events = [];

            %%% Recording
            % instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();

            % resolve a stream...
            disp('Resolving an EEG stream...');
            stream = {};
            while isempty(stream)
                stream = lsl_resolve_byprop(lib,'type','EEG');
                if isempty(stream)
                    error('There is no stream visible on the network.');
                end
            end

            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(stream{1});


            while true
                % Timestamps에 기록된 마지막 값과 첫번째 값의 차이가 time값보다 >= 되면 종료
                if length(timesum) > 2
                    if timesum(end) - timesum(1) >= preparation_time + time + 0.3
                        break
                    end

                    % Time panel
                    app.SecondEditField.Enable = "on";
                    app.MinuteEditField.Enable = "on";
                    if app.SecondEditField.Value ~= 0
                        app.SecondEditField.Value = floor(second - floor(timesum(end) - timesum(1)));
                    elseif app.SecondEditField.Value == 0 && app.MinuteEditField.Value ~= 0
                        minute = minute - 1;
                        app.MinuteEditField.Value = minute;
                        app.SecondEditField.Value = 60;
                        second = second + 60;
                    else
                        app.SecondEditField.Value = 0;
                        app.MinuteEditField.Value = 0;
                    end


                    % Trigger (Resting)
                    if timesum(end) - timesum(1) >= preparation_time + 2*(app.MI_MeasurementTimeSpinner.Value+app.RestingMeasurementTimeSpinner.Value)*resting_i
                        % Resting sound
                        switch app.RestingsoundCheckBox.Value
                            case 1
                                sound(app.sound_x1, app.sound_fs);
                        end

                        resting_events = [resting_events, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        % Signal acquisition protocol UIAxes
                        % Rest 구간(화면 가운데 부분) 설정
                        fill(app.ProtocolUIAxes, [-3.33, 3.33, 3.33, -3.33], [-1, -1, 1, 1], [1, 0.7, 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                        text(app.ProtocolUIAxes, 0, 0, 'Rest', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

                        % 축 범위 설정
                        xlim(app.ProtocolUIAxes, [-10, 10]);
                        ylim(app.ProtocolUIAxes, [-1, 1]);

                        % Rest 상태 활성화, 나머지 비활성화
                        app.RestingSoundImage.Enable = "on";
                        app.FistSoundImage.Enable = "off";
                        app.PalmSoundImage.Enable = "off";

                        % 카운트 증가
                        resting_i = resting_i + 1;
                        % pause(app.RestingMeasurementTimeSpinner.Value); % Resting 시간 동안 대기
                    end

                    % Trigger (Fist)
                    if timesum(end) - timesum(1) >= preparation_time + app.RestingMeasurementTimeSpinner.Value + 2*(app.MI_MeasurementTimeSpinner.Value+app.RestingMeasurementTimeSpinner.Value)*Fist_i
                        % Fist sound
                        switch app.FistsoundCheckBox.Value
                            case 1
                                sound(app.sound_x2, app.sound_fs);
                        end

                        Fist_events = [Fist_events, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        app.FistSoundImage.Enable = "on";
                        app.PalmSoundImage.Enable = "off";
                        app.RestingSoundImage.Enable = "off";

                        if app.FistSpinner.Value == 0
                            % 정적 Fist 처리
                            % UI 업데이트 및 상태 활성화
                            fill(app.ProtocolUIAxes, [-10, -3.33, -3.33, -10], [-1, -1, 1, 1], [0.7, 0.7, 1], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                            text(app.ProtocolUIAxes, -6.66, 0, 'Fist', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            xlim(app.ProtocolUIAxes, [-10, 10]);
                            ylim(app.ProtocolUIAxes, [-1, 1]);
                            Fist_i = Fist_i + 1;
                            pause(app.MI_MeasurementTimeSpinner.Value); % Fist 시간 동안 대기
                        else
                            % 깜빡임 처리
                            Fist_frequency = app.FistSpinner.Value; % 설정된 주파수
                            totalTime = app.MI_MeasurementTimeSpinner.Value; % 총 실행 시간
                            blinkInterval = 1 / (2 * Fist_frequency); % 반 주기 간격

                            startTime = tic; % 시작 시간 기록
                            elapsedTime = 0; % 초기 경과 시간
                            while elapsedTime < totalTime
                                % 현재 시간 계산
                                elapsedTime = toc(startTime);

                                % 짝수 반 주기: 불빛 켜기
                                if mod(floor(elapsedTime / blinkInterval), 2) == 0
                                    fill(app.ProtocolUIAxes, [-10, -3.33, -3.33, -10], ...
                                        [-1, -1, 1, 1], [0.7, 0.7, 1], ...
                                        'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                    % 홀수 반 주기: 불빛 끄기
                                else
                                    fill(app.ProtocolUIAxes, [-10, -3.33, -3.33, -10], ...
                                        [-1, -1, 1, 1], [0.7, 0.7, 1], ...
                                        'FaceAlpha', 0, 'EdgeColor', 'none');
                                end

                                % 즉시 화면 업데이트
                                drawnow;
                            end

                            Fist_i = Fist_i + 1;
                        end
                    end

                    % Trigger (Resting2)
                    if timesum(end) - timesum(1) >= preparation_time + app.RestingMeasurementTimeSpinner.Value + app.MI_MeasurementTimeSpinner.Value + 2*(app.MI_MeasurementTimeSpinner.Value+app.RestingMeasurementTimeSpinner.Value)*resting2_i
                        % Resting sound
                        switch app.RestingsoundCheckBox.Value
                            case 1
                                sound(app.sound_x1, app.sound_fs);
                        end


                        resting_events_2 = [resting_events_2, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        % Signal acquisition protocol UIAxes
                        % Rest 구간(화면 가운데 부분) 설정
                        fill(app.ProtocolUIAxes, [-3.33, 3.33, 3.33, -3.33], [-1, -1, 1, 1], [1, 0.7, 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                        text(app.ProtocolUIAxes, 0, 0, 'Rest', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

                        % 축 범위 설정
                        xlim(app.ProtocolUIAxes, [-10, 10]);
                        ylim(app.ProtocolUIAxes, [-1, 1]);

                        % Rest 상태 활성화, 나머지 비활성화
                        app.RestingSoundImage.Enable = "on";
                        app.FistSoundImage.Enable = "off";
                        app.PalmSoundImage.Enable = "off";

                        % 카운트 증가
                        resting2_i = resting2_i + 1;
                        % pause(app.RestingMeasurementTimeSpinner.Value); % Resting 시간 동안 대기
                    end

                    % Trigger (Palm)
                    if timesum(end) - timesum(1) >= preparation_time + 2*app.RestingMeasurementTimeSpinner.Value + app.MI_MeasurementTimeSpinner.Value + 2*(app.MI_MeasurementTimeSpinner.Value+app.RestingMeasurementTimeSpinner.Value)*Palm_i
                        
                        switch app.PalmsoundCheckBox.Value
                            case 1
                                sound(app.sound_x3, app.sound_fs);
                        end

                        Palm_events = [Palm_events, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        app.PalmSoundImage.Enable = "on";
                        app.FistSoundImage.Enable = "off";
                        app.RestingSoundImage.Enable = "off";

                        if app.PalmSpinner.Value == 0
                            % 정적 Palm 처리
                            % UI 업데이트 및 상태 활성화
                            fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], [-1, -1, 1, 1], [0.7, 1, 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                            text(app.ProtocolUIAxes, 6.67, 0, 'Palm', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            xlim(app.ProtocolUIAxes, [-10, 10]);
                            ylim(app.ProtocolUIAxes, [-1, 1]);
                            app.RepeatCountEditField.Value = app.RepeatCountEditField.Value + 1;
                            Palm_i = Palm_i + 1;
                            pause(app.MI_MeasurementTimeSpinner.Value); % Palm 시간 동안 대기
                        else
                            % 깜빡임 처리
                            Palm_frequency = app.PalmSpinner.Value; % 설정된 주파수
                            totalTime = app.MI_MeasurementTimeSpinner.Value; % 총 실행 시간
                            blinkInterval = 1 / (2 * Palm_frequency); % 반 주기 간격

                            startTime = tic; % 시작 시간 기록
                            elapsedTime = 0; % 초기 경과 시간
                            while elapsedTime < totalTime
                                % 현재 시간 계산
                                elapsedTime = toc(startTime);

                                % 짝수 반 주기: 불빛 켜기
                                if mod(floor(elapsedTime / blinkInterval), 2) == 0
                                    fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], ...
                                        [-1, -1, 1, 1], [0.7, 1, 0.7], ...
                                        'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                    % 홀수 반 주기: 불빛 끄기
                                else
                                    fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], ...
                                        [-1, -1, 1, 1], [0.7, 1, 0.7], ...
                                        'FaceAlpha', 0, 'EdgeColor', 'none');
                                end

                                % 즉시 화면 업데이트
                                drawnow;
                            end

                            app.RepeatCountEditField.Value = app.RepeatCountEditField.Value + 1;
                            Palm_i = Palm_i + 1;
                        end

                    end

                end

                % Inlet.pull_chunk를 통해 데이터 수집
                [chunk, timestamps] = inlet.pull_chunk();
                timesum = [timesum, timestamps];
                rawdata = [rawdata, chunk]; % channel X data (matrix)

                % 필터링을 위한 EEG 필드 추가
                plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)
                fft_rawdata_T = transpose(plot_rawdata); % data X channel (matrix)
                EEG.nbchan = size(plot_rawdata, 1);
                EEG.pnts = size(plot_rawdata, 2);
                EEG.data = plot_rawdata; % channel X data (matrix)
                EEG.xmax = (EEG.pnts-1)/EEG.srate;
                %{
            % filtering 함수
            i = length(rawdata);
            if i > 0
                EEG = pop_eegfiltnew(EEG, app.LowcutoffEditField.Value, app.HighcutoffEditField.Value, [], 0, [], 0);

                clear selected_channel
                selected_channel = [find(strcmp(app.Ch1ListBox.Items, app.Ch1ListBox.Value))];
                if app.Ch2CheckBox.Value == 1
%                     app.Ch2ListBox.Value
                    selected_channel = [selected_channel, find(strcmp(app.Ch2ListBox.Items, app.Ch2ListBox.Value))];
                end
                if app.Ch3CheckBox.Value == 1
%                     app.Ch3ListBox.Value
                    selected_channel = [selected_channel, find(strcmp(app.Ch3ListBox.Items, app.Ch3ListBox.Value))];
                end
                if app.Ch4CheckBox.Value == 1
%                     app.Ch4ListBox.Value
                    selected_channel = [selected_channel, find(strcmp(app.Ch4ListBox.Items, app.Ch4ListBox.Value))];
                end
                
                plot_rawdata_T = transpose(EEG.data); % data X channel (matrix)
                
                % Time domain UIAxes
                switch app.DisplayTdomainCheckBox.Value
                case 1
                    plot_rawdata_T2 = (plot_rawdata_T-mean(plot_rawdata_T))*10;
                    selected_plot_rawdata_T = plot_rawdata_T2(:, selected_channel);
                    k = length(plot_rawdata);
                    for j = 1:1:size(selected_channel, 2)
                        selected_plot_rawdata_T(:,j) = selected_plot_rawdata_T(:,j) + (500-10*j)*3000;    
                    end
                    
                    if k < 2000
                        plot(app.T_domainUIAxes, selected_plot_rawdata_T)
                        xlim(app.T_domainUIAxes, [0 2000])
                        drawnow;
                    elseif k >= 2000
                        plot(app.T_domainUIAxes, selected_plot_rawdata_T(k-1999:k,:))
                        xlim(app.T_domainUIAxes, [0 2000])
                        drawnow;          
                    end
                case 0
                end
                

                % Frequency domain UIAxes
                switch app.DisplayFdomainCheckBox.Value
                    case 1
                        if timesum(end) - timesum(1) >= preparation_time + (app.MI_MeasurementTimeSpinner.Value+app.RestingMeasurementTimeSpinner.Value)*fft_i+0.1
                            size_correction = 0;
                            if app.MI_MeasurementTimeSpinner.Value*EEG.srate > size(fft_rawdata_T(MI_events(fft_i)+1 : end, :),1)
                                size_correction = app.MI_MeasurementTimeSpinner.Value*EEG.srate - size(fft_rawdata_T(MI_events(fft_i)+1 : end, :),1);
                            end
                            fftdata_all = fft(fft_rawdata_T(MI_events(fft_i)+1 : MI_events(fft_i)+app.MI_MeasurementTimeSpinner.Value*EEG.srate-size_correction, :));
                            %                         disp(size_correction)
                            %                         disp(size(fft_rawdata_T(MI_events(fft_i)+1 : MI_events(fft_i)+app.MI_MeasurementTimeSpinner.Value*EEG.srate-size_correction, :),1))
                            %                         disp(size(fft_rawdata_T(MI_events(fft_i)+1 : end, :),1))

                            N = app.MI_MeasurementTimeSpinner.Value*EEG.srate;
                            fftdata_all = 2*abs(fftdata_all)/N;
                            fftdata = fftdata_all(:, selected_channel);

                            n = 0:N-1;
                            f_axis = EEG.srate*n/N;
                            cutoff = ceil(N/2);
                            fftdata = fftdata(1:cutoff,:);
                            f_axis = f_axis(1:cutoff);

                            selected_fftdata_value = zeros(1, length(selected_channel));
                            for i = 1:length(selected_channel)
                                selected_fftdata_value(i) = mean(fftdata((f_axis >= app.LowcutoffEditField.Value) & (f_axis <= app.HighcutoffEditField.Value),i));
                            end
                            xtick_labels = cell(1, length(selected_channel));
                            for i = 1:length(selected_channel)
                                xtick_labels{i} = strcat('Ch', num2str(i));
                            end

                            bar(app.F_domainUIAxes, selected_fftdata_value, 'FaceColor',[.5 .5 .5], 'LineWidth',1);
                            xticklabels(app.F_domainUIAxes, xtick_labels);

                            for i = 1:length(selected_fftdata_value)
                                text(app.F_domainUIAxes, i, selected_fftdata_value(i), num2str(selected_fftdata_value(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                            end

                            fft_i = fft_i + 1;
                        end
                    case 0
                end

                % Stop recording
                pause(10^-323)
                if app.stopFlag == true
                    complete_record = "no";
                    break
                end

            end
                %}
                % Stop recording
                pause(10^-323)
                if app.stopFlag == true
                    complete_record = "no";
                    break
                end

            end
            %{
        % Palm 구간(화면 오른쪽 부분) 설정 - 불빛 끄기
        fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], [-1, -1, 1, 1], [0.7, 1, 0.7], 'FaceAlpha', 0, 'EdgeColor', 'none');
        app.PalmSoundImage.Enable = "off";

        % 타이머 종료
        stop(app.timerObj);
        delete(app.timerObj);
            %}
            if complete_record == "yes"
                time_interval = app.MI_MeasurementTimeSpinner.Value;
                events(end) = [];
                y_train = repmat([0 1 0 2], 1, app.RepeatCountSpinner.Value);
                try
                    uisave({'rawdata', 'events', 'time_interval', 'y_train'},'')
                catch
                    disp('Try again')
                end
            end

            app.stopFlag = false;
            %cla(app.T_domainUIAxes) 삭제예정
            %cla(app.F_domainUIAxes) 삭제예정
            %cla(app.ProtocolUIAxes) 삭제예정
            app.RestingSoundImage.Enable = "off";
            app.FistSoundImage.Enable = "off";
            app.PalmSoundImage.Enable = "off";
            app.RepeatCountEditField.Value = 0;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;
            app.SecondEditField.Value = 0;
            app.MinuteEditField.Value = 0;

        end

        % Button pushed function: LoadtraningdataButton
        function LoadtraningdataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');

            if ~isequal(file, 0)
                fullFilePath = fullfile(path, file);
                app.load_data = load(fullFilePath);
                app.LocationFileLabel.Text = fullFilePath;
                time_interval = app.load_data.time_interval;

                app.WindowrangeSpinner.Limits = [0 time_interval];
                app.WindowrangeSpinner.Value = 0;
                app.WindowrangeSpinner_2.Limits = [0 time_interval];
                app.WindowrangeSpinner_2.Value = time_interval;
            end
            %             assignin('base', "load_data", app.load_data)

            app.LoaddataLabel.Text = "==> Train model <==";

        end

        % Value changed function: MI_MeasurementTimeSpinner
        function MI_MeasurementTimeSpinnerValueChanged(app, event)
            app.RestingMeasurementTimeSpinner.Value = app.MI_MeasurementTimeSpinner.Value;

        end

        % Value changed function: RestingMeasurementTimeSpinner
        function RestingMeasurementTimeSpinnerValueChanged(app, event)
            app.MI_MeasurementTimeSpinner.Value = app.RestingMeasurementTimeSpinner.Value;

        end

        % Button pushed function: TraningmodelButton
        function TraningmodelButtonPushed(app, event)
            % load channel locations

            app.locations = load('CGX-10-20-Cap19_2.mat');
            app.locations = app.locations.locations;

            app.LoaddataLabel.Text = "Prepocessing & training...";

            app.MI_MeasurementTimeSpinner_2.Value = app.load_data.time_interval;

            % filte bank
            app.filter_bank = {[app.LFBEditField_1.Value app.HFBEditField_1.Value], ...
                [app.LFBEditField_2.Value app.HFBEditField_2.Value], ...
                [app.LFBEditField_3.Value app.HFBEditField_3.Value], ...
                [app.LFBEditField_4.Value app.HFBEditField_4.Value], ...
                [app.LFBEditField_5.Value app.HFBEditField_5.Value], ...
                [app.LFBEditField_6.Value app.HFBEditField_6.Value], ...
                [app.LFBEditField_7.Value app.HFBEditField_7.Value], ...
                [app.LFBEditField_8.Value app.HFBEditField_8.Value], ...
                [app.LFBEditField_9.Value app.HFBEditField_9.Value], ...
                [app.LFBEditField_10.Value app.HFBEditField_10.Value]};

            % traning data
            rawdata = app.load_data.rawdata;
            events = app.load_data.events;
            time_interval = app.load_data.time_interval;
            y_train = app.load_data.y_train;
            trial = length(y_train);

            rawdata = rawdata(1:size(app.locations,1),:);

            % Common Average Reference (CAR)
            if app.CARCheckBox.Value == 1
                rawdata = rawdata-repmat(mean(rawdata,1), size(rawdata,1), 1);
            end

            % selected channels
            checkedNodes = app.Tree.CheckedNodes;
            app.checkedIndices = [];
            for i = 1:length(checkedNodes)
                nodeText = checkedNodes(i).Text;
                idx = find(strcmp(app.locations.labels, nodeText));
                if ~isempty(idx)
                    app.checkedIndices = [app.checkedIndices, idx];
                end
            end
            disp(app.checkedIndices)
            rawdata = rawdata(app.checkedIndices,:);


            % Bandpass filter
            EEG = struct;
            EEG.event = [];
            EEG.srate = app.sample_rate;
            EEG.nbchan = size(rawdata, 1);
            EEG.pnts = size(rawdata, 2);
            EEG.trials = 1;
            EEG.data = rawdata;
            EEG = pop_eegfiltnew(EEG, app.LowcutoffEditField_3.Value, app.HighcutoffEditField_3.Value, [], 0, 0, 0, 0, 0);
            filtered_data = EEG.data;

            % Epoching data
            wnd_ft=[0 time_interval];
            for i=1:trial
                epoched_data(:,:,i) = filtered_data(:,round(events(i)+(wnd_ft(1)*EEG.srate)):round(events(i)+(wnd_ft(2)*EEG.srate)));
            end

            % Down sampling
            if app.DownsamplingSpinner.Value ~= app.sample_rate
                % EEG2 struct
                EEG2 = struct;
                EEG2.setname = 'csvfile';
                EEG2.event = [];
                EEG2.nbchan = size(epoched_data, 1);
                EEG2.pnts = size(epoched_data, 2);
                EEG2.trials = size(epoched_data, 3);
                EEG2.srate = app.sample_rate;
                EEG2.xmin = 0;
                EEG2.xmax = (EEG2.pnts-1)/EEG2.srate;
                EEG2.data = epoched_data;
                EEG2 = pop_resample(EEG2, app.DownsamplingSpinner.Value);
                downsampled_data = EEG2.data;
            elseif app.DownsamplingSpinner.Value == app.sample_rate
                downsampled_data = epoched_data;
            end

            % Window range
            window_range_data = downsampled_data(:, floor(app.WindowrangeSpinner.Value*app.DownsamplingSpinner.Value+1) : app.WindowrangeSpinner_2.Value*app.DownsamplingSpinner.Value, :);
            x_train = window_range_data; % channel X data

            % segment
            if app.WindowSplitSpinner.Value >= 2
                end_split = floor(size(x_train,2)/app.WindowSplitSpinner.Value);
                z = 1;
                for i = 1:trial
                    for j = 0:app.WindowSplitSpinner.Value - 1
                        x_train2(:,:,z) = x_train(:, (end_split*j)+1:(end_split*(j+1)), i);
                        y_train2(z) = y_train(i);
                        z = z + 1;
                    end
                end
                x_train = x_train2;
                y_train = y_train2;
            end
            %             assignin('base', "x_train2", x_train2)
            %             assignin('base', "y_train2", y_train2)
            %             assignin('base', "x_train", x_train)
            %             assignin('base', "y_train", y_train)

            % power baseline
            rest_x_train = x_train(:, :, y_train==0); % Rest 데이터만 추출

            % Rest 데이터를 기준으로 baseline을 계산 (Rest만 사용)
%             baseline_rest = mean(rest_x_train, 3); % Rest 상태의 평균을 구합니다.
            baseline_rest = reshape(rest_x_train, size(rest_x_train,1), size(rest_x_train,2)*size(rest_x_train,3));

            % Rest 데이터 제거
            rest_indices = (y_train == 0); % Rest 데이터의 인덱스 찾기
            x_train(:, :, rest_indices) = []; % Rest 데이터 제거
            y_train(rest_indices) = [];       % Rest 라벨 제거

            % 결과 확인
            disp("Rest 데이터 제거 완료");
            disp(size(x_train)); % 남은 데이터의 크기 확인
            disp(size(y_train)); % 남은 라벨의 크기 확인

            % feature extraction
            if app.BandpowerButton.Value == 1 || app.ERDERSButton.Value == 1
                feature_x_train = [];
                total_band_power = [];
                total_erd_ers = [];
                feature_i = 1;

                % Fist와 Palm 상태에 대해서 baseline을 제거하지 않고 그대로 피처 추출
                for ch = 1:size(x_train, 1) % 채널 수
                    for fb = 1:app.FilterbankSpinner.Value % filterbank 수
                        % Rest 데이터 기준으로 baseline 계산 (이미 baseline_rest가 있음)
                        power_baseline = bandpower(baseline_rest(ch,:), app.DownsamplingSpinner.Value, app.filter_bank{fb});

                        for tr = 1:size(x_train, 3) % trial 수
                            power_event = bandpower(x_train(ch,:,tr), app.DownsamplingSpinner.Value, app.filter_bank{fb});
                            total_band_power(feature_i, tr) = power_event;
                            total_erd_ers(feature_i, tr) = ((power_event - power_baseline) / power_baseline) * 100;
                        end
                        feature_i = feature_i + 1;
                    end
                end

                % Bandpower 및 ERD/ERS 계산 결과를 feature에 추가
                if app.BandpowerButton.Value == 1
                    feature_x_train = [feature_x_train; total_band_power];
                end
                if app.ERDERSButton.Value == 1
                    feature_x_train = [feature_x_train; total_erd_ers];
                end

                % 결과를 base workspace에 저장
                assignin('base', "feature_x_train", feature_x_train)
            end


            % Common Spatial Pattern (CSP)
            %             if app.CSPButton.Value == 1
            %
            %             end


            feature_x_train = transpose(feature_x_train);
            y_train = transpose(y_train);
            assignin('base', "feature_x_train", feature_x_train)
            assignin('base', "y_train", y_train)

            %             MI_indices = find(y_train==1);
            %             resting_indices = find(y_train==0);
            %             MI_indices = MI_indices(randperm(length(MI_indices)));
            %             resting_indices = resting_indices(randperm(length(resting_indices)));
            %             suffled_feature_x_train = [feature_x_train(resting_indices) feature_x_train(MI_indices)];
            %             suffled_y_train = [y_train(resting_indices) ; y_train(MI_indices)];

            % shuffle data
            indices = randperm(length(y_train));
            shuffled_feature_x_train = feature_x_train(indices,:);
            shuffled_y_train = y_train(indices,:);
            assignin('base', "shuffled_feature_x_train", shuffled_feature_x_train)
            assignin('base', "shuffled_y_train", shuffled_y_train)
            app.SVMModel = fitclinear(shuffled_feature_x_train, shuffled_y_train);
            %             app.SVMModel = fitcecoc(feature_x_train, y_train);
            %             app.SVMModel = fitcsvm(feature_x_train, y_train);
            %             assignin('base', "SVMModel", app.SVMModel)

            app.LoaddataLabel.Text = "==> Done <==";

        end

        % Button pushed function: RecordButton_2
        function RecordButton_2Pushed(app, event)
            %%% Parameters and variables setting
            preparation_time = 0;

            time = (2*app.MI_MeasurementTimeSpinner_2.Value + 2*app.RestingMeasurementTimeSpinner_2.Value)*app.RepeatCountSpinner_2.Value + preparation_time;

            if time >= 60
                app.MinuteEditField_2.Value = fix(time/60);
                app.SecondEditField_2.Value = rem(time,60);
            elseif time < 60
                app.MinuteEditField_2.Value = 0;
                app.SecondEditField_2.Value = time;
            end

            minute = app.MinuteEditField_2.Value;
            second = app.SecondEditField_2.Value;

            % load channel locations
            app.locations = load('CGX-10-20-Cap19_2.mat');
            app.locations = app.locations.locations;

            complete_record = "yes";

            rawdata = []; % channel X data (matrix)
            timesum = []; % 총 측정시간이 입력될 백터

            resting_i = 0;
            Fist_i = 0;
            resting2_i = 0;
            Palm_i = 0;
            fft_i = 1;

            resting_events = [];
            Fist_events = [];
            resting_events_2 = [];
            Palm_events = [];
            events = [];

            Fist_accuracy_sum = 0;
            Palm_accuracy_sum = 0;
            Fist_test_num = 0;
            Palm_test_num = 0;
            Fist_result_text = {};
            Palm_result_text = {};
            Total_result_text = {};
            Fist_result_num = 0;
            Palm_result_num = 0;

            % EEG struct
            EEG.setname = 'csvfile';
            EEG.filename = '';
            EEG.filepath = '';
            EEG.subject = '';
            EEG.nbchan = size(app.locations,1);
            EEG.trials = 1;
            EEG.pnts = [];
            EEG.srate = app.sample_rate;
            EEG.xmin = 0;
            EEG.xmax = [];
            EEG.data = [];
            EEG.icaact = [];
            EEG.icawinv = [];
            EEG.icasphere = [];
            EEG.icaweights = [];
            EEG.icachansind = [];
            location_struct = table2struct(app.locations);
            EEG.chanlocs = location_struct;
            EEG.urchanlocs = [];
            EEG.chaninfo = [];
            EEG.event = [];
            EEG.urevent = [];
            EEG.epoch = [];
            EEG.reject = [];
            EEG.specdata = [];
            EEG.specicaact = [];
            EEG.etc = [];


            %%% Recording
            % instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();

            % resolve a stream...
            disp('Resolving an EEG stream...');
            stream = {};
            while isempty(stream)
                stream = lsl_resolve_byprop(lib,'type','EEG');
                if isempty(stream)
                    error('There is no stream visible on the network.');
                end
            end

            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(stream{1});

            while true
                % Timestamps에 기록된 마지막 값과 첫번째 값의 차이가 time값보다 >= 되면 종료
                if length(timesum) > 2
                    if timesum(end) - timesum(1) >= preparation_time + time + 0.3
                        break
                    end

                    % Time panel
                    app.SecondEditField_2.Enable = "on";
                    app.MinuteEditField_2.Enable = "on";
                    if app.SecondEditField_2.Value ~= 0
                        app.SecondEditField_2.Value = floor(second - floor(timesum(end) - timesum(1)));
                    elseif app.SecondEditField_2.Value == 0 && app.MinuteEditField_2.Value ~= 0
                        minute = minute - 1;
                        app.MinuteEditField_2.Value = minute;
                        app.SecondEditField_2.Value = 60;
                        second = second + 60;
                    else
                        app.SecondEditField_2.Value = 0;
                        app.MinuteEditField_2.Value = 0;
                    end

                    % Trigger (Resting)
                    if timesum(end) - timesum(1) >= preparation_time + 2*(app.MI_MeasurementTimeSpinner_2.Value+app.RestingMeasurementTimeSpinner_2.Value)*resting_i


                        % 학습된 SVM에 전처리된 데이터를 입력으로 전달
                        resting_events = [resting_events, find(timesum == timesum(end))];
                        assignin('base',"resting_events",resting_events)
                        events = [events, find(timesum == timesum(end))];


                        % real-time signal preprocessing
                        if Palm_i >= 1
                            test_rawdata = plot_rawdata(:, resting_events(Palm_i):resting_events(Palm_i+1)); % channel X data (matrix)
                            %                         assignin('base',"Palm_i",Palm_i)
                            %                         assignin('base',"test_rawdata",test_rawdata)
                            %                         assignin('base',"resting_events",resting_events)

                            % Common Average Reference (CAR)
                            if app.CARCheckBox.Value == 1
                                car_test_data = test_rawdata-repmat(mean(test_rawdata,1), size(test_rawdata,1), 1);
                            else
                                car_test_data = test_rawdata;
                            end

                            assignin('base', "car_test_data", car_test_data)

                            % selected channels
                            car_test_data = car_test_data(app.checkedIndices,:);

                            assignin('base', "car_test_data2", car_test_data)


                            % EEG2 struct
                            EEG2 = struct;
                            EEG2.setname = 'csvfile';
                            EEG2.event = [];
                            EEG2.nbchan = size(car_test_data, 1);
                            EEG2.pnts = size(car_test_data, 2);
                            EEG2.trials = 1;
                            EEG2.srate = app.sample_rate;
                            EEG2.xmin = 0;
                            EEG2.xmax = (EEG2.pnts-1)/EEG2.srate;
                            EEG2.data = car_test_data;


                            % Bandpass filter
                            EEG2 = pop_eegfiltnew(EEG2, app.LowcutoffEditField_3.Value, app.HighcutoffEditField_3.Value, [], 0, 0, 0, 0, 0);

                            % separate Palm_data and Fist_data
                            Palm_test_data = EEG2.data(:, end-(app.MI_MeasurementTimeSpinner_2.Value*EEG2.srate) : end);
                            Fist_test_data = EEG2.data(:, 1+app.RestingMeasurementTimeSpinner_2.Value*EEG2.srate : (app.RestingMeasurementTimeSpinner_2.Value+app.MI_MeasurementTimeSpinner_2.Value)*EEG2.srate);
                            resting_test_data = EEG2.data(:, 1 : app.RestingMeasurementTimeSpinner_2.Value*EEG2.srate);
                            %                         assignin('base',"EEG2data",EEG2.data)
                            %                         assignin('base',"MI_test_data",MI_test_data)
                            %                         assignin('base',"resting_test_data",resting_test_data)

                            % Down sampling
                            if app.DownsamplingSpinner.Value ~= app.sample_rate
                                % EEG2 struct
                                EEG3 = struct;
                                EEG3.setname = 'csvfile';
                                EEG3.event = [];
                                EEG3.nbchan = size(Palm_test_data, 1);
                                EEG3.pnts = size(Palm_test_data, 2);
                                EEG3.trials = 1;
                                EEG3.srate = app.sample_rate;
                                EEG3.xmin = 0;
                                EEG3.xmax = (EEG3.pnts-1)/EEG3.srate;
                                EEG3.data = Palm_test_data;
                                EEG3 = pop_resample(EEG3, app.DownsamplingSpinner.Value);
                                EEG4 = struct;
                                EEG4.setname = 'csvfile';
                                EEG4.event = [];
                                EEG4.nbchan = size(Fist_test_data, 1);
                                EEG4.pnts = size(Fist_test_data, 2);
                                EEG4.trials = 1;
                                EEG4.srate = app.sample_rate;
                                EEG4.xmin = 0;
                                EEG4.xmax = (EEG4.pnts-1)/EEG4.srate;
                                EEG4.data = Fist_test_data;
                                EEG4 = pop_resample(EEG4, app.DownsamplingSpinner.Value);
                                EEG5 = struct;
                                EEG5.setname = 'csvfile';
                                EEG5.event = [];
                                EEG5.nbchan = size(Fist_test_data, 1);
                                EEG5.pnts = size(Fist_test_data, 2);
                                EEG5.trials = 1;
                                EEG5.srate = app.sample_rate;
                                EEG5.xmin = 0;
                                EEG5.xmax = (EEG5.pnts-1)/EEG5.srate;
                                EEG5.data = Fist_test_data;
                                EEG5 = pop_resample(EEG5, app.DownsamplingSpinner.Value);

                                downsampled_Palm_test_data = EEG3.data;
                                downsampled_Fist_test_data = EEG4.data;
                                downsampled_resting_test_data = EEG5.data;
                            elseif app.DownsamplingSpinner.Value == app.sample_rate
                                downsampled_Palm_test_data = Palm_test_data;
                                downsampled_Fist_test_data = Fist_test_data;
                                downsampled_resting_test_data = resting_test_data;
                            end

                            % Window range(Palm)
                            window_range__Palm_test_data = downsampled_Palm_test_data(:, floor(app.WindowrangeSpinner.Value*app.DownsamplingSpinner.Value+1) : app.WindowrangeSpinner_2.Value*app.DownsamplingSpinner.Value);
                            x_test = window_range__Palm_test_data; % channel X data
                            %                         assignin('base',"window_range_data",window_range_data)

                            % Window range(Fist)
                            window_range__Fist_test_data = downsampled_Fist_test_data(:, floor(app.WindowrangeSpinner.Value*app.DownsamplingSpinner.Value+1) : app.WindowrangeSpinner_2.Value*app.DownsamplingSpinner.Value);
                            y_test = window_range__Fist_test_data; % channel X data
                            %                         assignin('base',"window_range_data",window_range_data)

                            % Window range(resting)
                            window_range__resting_test_data = downsampled_resting_test_data(:, floor(app.WindowrangeSpinner.Value*app.DownsamplingSpinner.Value+1) : app.WindowrangeSpinner_2.Value*app.DownsamplingSpinner.Value);
                            z_test = window_range__resting_test_data; % channel X data
                            %                         assignin('base',"window_range_data",window_range_data)

                            % segment(Palm)
                            if app.WindowSplitSpinner.Value >= 2
                                end_split = floor(size(x_test,2)/app.WindowSplitSpinner.Value);
                                %                             end_split2 = floor(size(downsampled_resting_test_data,2)/app.WindowSplitSpinner.Value);
                                z = 1;
                                for j = 0:app.WindowSplitSpinner.Value - 1
                                    x_test2(:,:,z) = x_test(:, (end_split*j)+1:(end_split*(j+1)));
                                    %                                 downsampled_resting_test_data2(:,:,z) = downsampled_resting_test_data(:, (end_split2*j)+1:(end_split2*(j+1)));
                                    z = z + 1;
                                end
                                x_test = x_test2;
                                %                             downsampled_resting_test_data = downsampled_resting_test_data2;
                            end
                            %                         assignin('base',"x_test",x_test)

                            % segment(Fist)
                            if app.WindowSplitSpinner.Value >= 2
                                end_split = floor(size(y_test,2)/app.WindowSplitSpinner.Value);
                                %                             end_split2 = floor(size(downsampled_resting_test_data,2)/app.WindowSplitSpinner.Value);
                                z = 1;
                                for j = 0:app.WindowSplitSpinner.Value - 1
                                    y_test2(:,:,z) = y_test(:, (end_split*j)+1:(end_split*(j+1)));
                                    %                                 downsampled_resting_test_data2(:,:,z) = downsampled_resting_test_data(:, (end_split2*j)+1:(end_split2*(j+1)));
                                    z = z + 1;
                                end
                                y_test = y_test2;
                                %                             downsampled_resting_test_data = downsampled_resting_test_data2;
                            end
                            %                         assignin('base',"x_test",x_test)

                            %{

                            % segment(resting)
                            if app.WindowSplitSpinner.Value >= 2
                                end_split = floor(size(z_test,2)/app.WindowSplitSpinner.Value);
                                %                             end_split2 = floor(size(downsampled_resting_test_data,2)/app.WindowSplitSpinner.Value);
                                z = 1;
                                for j = 0:app.WindowSplitSpinner.Value - 1
                                    z_test2(:,:,z) = z_test(:, (end_split*j)+1:(end_split*(j+1)));
                                    %                                 downsampled_resting_test_data2(:,:,z) = downsampled_resting_test_data(:, (end_split2*j)+1:(end_split2*(j+1)));
                                    z = z + 1;
                                end
                                z_test = z_test2;
                                %                             downsampled_resting_test_data = downsampled_resting_test_data2;
                            end
                            %                         assignin('base',"x_test",x_test)

                            %}

                            % power baseline
                            % 1. if segment => mean
                            %                         baseline_rest = mean(downsampled_resting_test_data, 3);
                            %                         assignin('base', "baseline_rest", baseline_rest)

                            % 2. Use as it is
                            baseline_rest = downsampled_resting_test_data;
                            %                         assignin('base', "baseline_rest", baseline_rest)

                            % feature extraction
                            if app.BandpowerButton.Value == 1 || app.ERDERSButton.Value == 1
                                feature_x_test = [];
                                total_band_power = [];
                                total_erd_ers = [];
                                feature_i = 1;
                                for ch = 1:size(x_test, 1) % channel num
                                    for fb = 1:app.FilterbankSpinner.Value % filterbank num
                                        power_baseline = bandpower(baseline_rest(ch,:), app.DownsamplingSpinner.Value, app.filter_bank{fb});
                                        for tr = 1:size(x_test, 3) % trial num
                                            power_event = bandpower(x_test(ch,:,tr), app.DownsamplingSpinner.Value, app.filter_bank{fb});
                                            total_band_power(feature_i,tr) = power_event;
                                            total_erd_ers(feature_i,tr) = ((power_event - power_baseline)/power_baseline) * 100;
                                        end
                                        feature_i = feature_i + 1;
                                    end
                                end

                                if app.BandpowerButton.Value == 1
                                    feature_x_test = [feature_x_test ; total_band_power];
                                end
                                if app.ERDERSButton.Value == 1
                                    feature_x_test = [feature_x_test ; total_erd_ers];
                                end
                            end


                            % feature extraction
                            if app.BandpowerButton.Value == 1 || app.ERDERSButton.Value == 1
                                feature_y_test = [];
                                total_band_power_2 = [];
                                total_erd_ers_2 = [];
                                feature_j = 1;
                                for ch = 1:size(y_test, 1) % channel num
                                    for fb = 1:app.FilterbankSpinner.Value % filterbank num
                                        power_baseline_2 = bandpower(baseline_rest(ch,:), app.DownsamplingSpinner.Value, app.filter_bank{fb});
                                        for tr = 1:size(y_test, 3) % trial num
                                            power_event_2 = bandpower(y_test(ch,:,tr), app.DownsamplingSpinner.Value, app.filter_bank{fb});
                                            total_band_power_2(feature_j,tr) = power_event_2;
                                            total_erd_ers_2(feature_j,tr) = ((power_event_2 - power_baseline_2)/power_baseline_2) * 100;
                                        end
                                        feature_j = feature_j + 1;
                                    end
                                end

                                if app.BandpowerButton.Value == 1
                                    feature_y_test = [feature_y_test ; total_band_power_2];
                                end
                                if app.ERDERSButton.Value == 1
                                    feature_y_test = [feature_y_test ; total_erd_ers_2];
                                end
                            end

                            % Common Spatial Pattern (CSP)
                            %                         if app.CSPButton.Value == 1
                            %
                            %                         end

                            feature_x_test = transpose(feature_x_test);
                            feature_y_test = transpose(feature_y_test);
                            %                         assignin('base', "feature_x_test", feature_x_test)

                            % SVM_model predict(Palm)
                            predicted_labels_Palm = predict(app.SVMModel, feature_x_test);
                            assignin('base', "predicted_labels_Palm", predicted_labels_Palm)

                            % SVM_model predict(Fist)
                            predicted_labels_Fist = predict(app.SVMModel, feature_y_test);
                            assignin('base', "predicted_labels_Fist", predicted_labels_Fist)


                            % Initialize accuracy counters for Fist and Palm
                            correct_Fist = 0;
                            correct_Palm = 0;

                            % Update Accuracy and Results
                            for count = 1:app.WindowSplitSpinner.Value
                                 % Update accuracy counters
                                 if predicted_labels_Fist(count) == 1 % Fist correctly classified
                                     correct_Fist = correct_Fist + 1;
                                 end
                                 if predicted_labels_Palm(count) == 2 % Palm correctly classified
                                     correct_Palm = correct_Palm + 1;
                                 end

                                % Generate result text for Fist
                                if predicted_labels_Fist(count) == 1
                                    Fist_result_text{end+1} = strcat('  [', num2str(count+Fist_result_num), ']', ' | ', 'Output: 1 (Fist), T');
                                elseif predicted_labels_Fist(count) == 2
                                    Fist_result_text{end+1} = strcat('  [', num2str(count+Fist_result_num), ']', ' | ', 'Output: 2 (Palm), F');
                                end
                                

                                % Generate result text for Palm
                                if predicted_labels_Palm(count) == 2
                                    Palm_result_text{end+1} = strcat('  [', num2str(count+Palm_result_num), ']', ' | ', 'Output: 2 (Palm), T');
                                elseif predicted_labels_Palm(count) == 1
                                    Palm_result_text{end+1} = strcat('  [', num2str(count+Palm_result_num), ']', ' | ', 'Output: 1 (Fist), F');
                                end
                                

                                if predicted_labels_Fist(count) == 1
                                    predicted_labels_Fist(count) = 1;
                                elseif predicted_labels_Fist(count) == 2
                                    predicted_labels_Fist(count) = 0;
                                end

                                if predicted_labels_Palm(count) == 2
                                    predicted_labels_Palm(count) = 1;
                                elseif predicted_labels_Palm(count) == 1
                                    predicted_labels_Palm(count) = 0;
                                end


                                % Save result counters
                                Total_result_text{end+1} = Fist_result_text{end};
                                Total_result_text{end+1} = Palm_result_text{end};


                            end
                            Fist_result_num = Fist_result_num + app.WindowSplitSpinner.Value;
                            app.FistResultTextArea.Value = strjoin(Fist_result_text, newline);
                            Palm_result_num = Palm_result_num + app.WindowSplitSpinner.Value;
                            app.PalmResultTextArea.Value = strjoin(Palm_result_text, newline);

                            app.TotalResultTextArea.Value = strjoin(Total_result_text, newline);


                            % Accuracy (%) Fist
                            Fist_accuracy_sum = Fist_accuracy_sum + sum(predicted_labels_Fist);
                            Fist_test_num = Fist_test_num + app.WindowSplitSpinner.Value;
                            app.AccuracyEditField_Fist.Value = (Fist_accuracy_sum/Fist_test_num)*100;
                            %                         assignin('base', "MI_test_num", MI_test_num)

                            % Accuracy (%) Palm
                            Palm_accuracy_sum = Palm_accuracy_sum + sum(predicted_labels_Palm);
                            assignin('base', "predicted_labels_Palm", predicted_labels_Palm)
                            Palm_test_num = Palm_test_num + app.WindowSplitSpinner.Value;
                            app.AccuracyEditField_Palm.Value = (Palm_accuracy_sum/Palm_test_num)*100;
                            %                         assignin('base', "MI_test_num", MI_test_num)

                            % Display accuracy in app
                            app.AccuracyEditField.Value = (app.AccuracyEditField_Fist.Value + app.AccuracyEditField_Palm.Value) / 2;
                            %app.TotalResultTextArea.Value = strjoin(result_text, newline);

                            % Save result counters
%                             Total_result_text{end+1} = Fist_result_text{end};
%                             Total_result_text{end+1} = Palm_result_text{end};
%                             app.TotalResultTextArea.Value = strjoin(Total_result_text, newline);


                        end

                        % Resting sound
                        switch app.RestingsoundCheckBox_2.Value
                            case 1
                                sound(app.sound_x1, app.sound_fs);
                        end


                        % Signal acquisition protocol UIAxes
                        % Rest 구간(화면 가운데 부분) 설정
                        fill(app.ProtocolUIAxes, [-3.33, 3.33, 3.33, -3.33], [-1, -1, 1, 1], [1, 0.7, 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                        text(app.ProtocolUIAxes, 0, 0, 'Rest', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

                        % 축 범위 설정
                        xlim(app.ProtocolUIAxes, [-10, 10]);
                        ylim(app.ProtocolUIAxes, [-1, 1]);

                        % Rest 상태 활성화, 나머지 비활성화
                        app.RestingSoundImage.Enable = "on";
                        app.FistSoundImage.Enable = "off";
                        app.PalmSoundImage.Enable = "off";

                        % 카운트 증가
                        resting_i = resting_i + 1;
                        % pause(app.RestingMeasurementTimeSpinner.Value); % Resting 시간 동안 대기
                    end

                    % Trigger (Fist)
                    if timesum(end) - timesum(1) >= preparation_time + app.RestingMeasurementTimeSpinner_2.Value + 2*(app.MI_MeasurementTimeSpinner_2.Value+app.RestingMeasurementTimeSpinner_2.Value)*Fist_i

                        switch app.FistsoundCheckBox_2.Value
                            case 1
                                sound(app.sound_x2, app.sound_fs);
                        end

                        Fist_events = [Fist_events, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        app.FistSoundImage.Enable = "on";
                        app.PalmSoundImage.Enable = "off";
                        app.RestingSoundImage.Enable = "off";

                        if app.FistSpinner.Value == 0
                            % 정적 Fist 처리
                            % UI 업데이트 및 상태 활성화
                            fill(app.ProtocolUIAxes, [-10, -3.33, -3.33, -10], [-1, -1, 1, 1], [0.7, 0.7, 1], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                            text(app.ProtocolUIAxes, -6.66, 0, 'Fist', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            xlim(app.ProtocolUIAxes, [-10, 10]);
                            ylim(app.ProtocolUIAxes, [-1, 1]);
                            Fist_i = Fist_i + 1;
                            pause(app.MI_MeasurementTimeSpinner_2.Value); % Fist 시간 동안 대기
                        else
                            % 깜빡임 처리
                            Fist_frequency = app.FistSpinner.Value; % 설정된 주파수
                            totalTime = app.MI_MeasurementTimeSpinner_2.Value; % 총 실행 시간
                            blinkInterval = 1 / (2 * Fist_frequency); % 반 주기 간격

                            startTime = tic; % 시작 시간 기록
                            elapsedTime = 0; % 초기 경과 시간
                            while elapsedTime < totalTime
                                % 현재 시간 계산
                                elapsedTime = toc(startTime);

                                % 짝수 반 주기: 불빛 켜기
                                if mod(floor(elapsedTime / blinkInterval), 2) == 0
                                    fill(app.ProtocolUIAxes, [-10, -3.33, -3.33, -10], ...
                                        [-1, -1, 1, 1], [0.7, 0.7, 1], ...
                                        'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                    % 홀수 반 주기: 불빛 끄기
                                else
                                    fill(app.ProtocolUIAxes, [-10, -3.33, -3.33, -10], ...
                                        [-1, -1, 1, 1], [0.7, 0.7, 1], ...
                                        'FaceAlpha', 0, 'EdgeColor', 'none');
                                end

                                % 즉시 화면 업데이트
                                drawnow;
                            end

                            Fist_i = Fist_i + 1;
                        end
                    end

                    % Trigger (Resting2)
                    if timesum(end) - timesum(1) >= preparation_time + app.RestingMeasurementTimeSpinner_2.Value + app.MI_MeasurementTimeSpinner_2.Value + 2*(app.MI_MeasurementTimeSpinner_2.Value+app.RestingMeasurementTimeSpinner_2.Value)*resting2_i
                        % Resting sound
                        switch app.RestingsoundCheckBox_2.Value
                            case 1
                                sound(app.sound_x1, app.sound_fs);
                        end


                        resting_events_2 = [resting_events_2, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        % Signal acquisition protocol UIAxes
                        % Rest 구간(화면 가운데 부분) 설정
                        fill(app.ProtocolUIAxes, [-3.33, 3.33, 3.33, -3.33], [-1, -1, 1, 1], [1, 0.7, 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                        text(app.ProtocolUIAxes, 0, 0, 'Rest', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

                        % 축 범위 설정
                        xlim(app.ProtocolUIAxes, [-10, 10]);
                        ylim(app.ProtocolUIAxes, [-1, 1]);

                        % Rest 상태 활성화, 나머지 비활성화
                        app.RestingSoundImage.Enable = "on";
                        app.FistSoundImage.Enable = "off";
                        app.PalmSoundImage.Enable = "off";

                        % 카운트 증가
                        resting2_i = resting2_i + 1;
                        % pause(app.RestingMeasurementTimeSpinner.Value); % Resting 시간 동안 대기
                    end

                    % Trigger (Palm)
                    if timesum(end) - timesum(1) >= preparation_time + 2*app.RestingMeasurementTimeSpinner_2.Value + app.MI_MeasurementTimeSpinner_2.Value + 2*(app.MI_MeasurementTimeSpinner_2.Value+app.RestingMeasurementTimeSpinner_2.Value)*Palm_i

                        switch app.PalmsoundCheckBox_2.Value
                            case 1
                                sound(app.sound_x3, app.sound_fs);
                        end

                        Palm_events = [Palm_events, find(timesum == timesum(end))];
                        events = [events, find(timesum == timesum(end))];

                        app.PalmSoundImage.Enable = "on";
                        app.FistSoundImage.Enable = "off";
                        app.RestingSoundImage.Enable = "off";

                        if app.PalmSpinner.Value == 0
                            % 정적 Palm 처리
                            % UI 업데이트 및 상태 활성화
                            fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], [-1, -1, 1, 1], [0.7, 1, 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                            text(app.ProtocolUIAxes, 6.67, 0, 'Palm', 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            xlim(app.ProtocolUIAxes, [-10, 10]);
                            ylim(app.ProtocolUIAxes, [-1, 1]);
                            app.RepeatCountEditField.Value = app.RepeatCountEditField.Value + 1;
                            Palm_i = Palm_i + 1;
                            pause(app.MI_MeasurementTimeSpinner_2.Value); % Palm 시간 동안 대기
                        else
                            % 깜빡임 처리
                            Palm_frequency = app.PalmSpinner.Value; % 설정된 주파수
                            totalTime = app.MI_MeasurementTimeSpinner_2.Value; % 총 실행 시간
                            blinkInterval = 1 / (2 * Palm_frequency); % 반 주기 간격

                            startTime = tic; % 시작 시간 기록
                            elapsedTime = 0; % 초기 경과 시간
                            while elapsedTime < totalTime
                                % 현재 시간 계산
                                elapsedTime = toc(startTime);

                                % 짝수 반 주기: 불빛 켜기
                                if mod(floor(elapsedTime / blinkInterval), 2) == 0
                                    fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], ...
                                        [-1, -1, 1, 1], [0.7, 1, 0.7], ...
                                        'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                    % 홀수 반 주기: 불빛 끄기
                                else
                                    fill(app.ProtocolUIAxes, [3.33, 10, 10, 3.33], ...
                                        [-1, -1, 1, 1], [0.7, 1, 0.7], ...
                                        'FaceAlpha', 0, 'EdgeColor', 'none');
                                end

                                % 즉시 화면 업데이트
                                drawnow;
                            end

                            app.RepeatCountEditField.Value = app.RepeatCountEditField.Value + 1;
                            Palm_i = Palm_i + 1;
                        end

                    end

                end


                % Inlet.pull_chunk를 통해 데이터 수집
                [chunk, timestamps] = inlet.pull_chunk();
                timesum = [timesum, timestamps];
                rawdata = [rawdata, chunk]; % channel X data (matrix)

                % 필터링을 위한 EEG 필드 추가
                plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)
                fft_rawdata_T = transpose(plot_rawdata); % data X channel (matrix)
                EEG.nbchan = size(plot_rawdata, 1);
                EEG.pnts = size(plot_rawdata, 2);
                EEG.xmax = (EEG.pnts-1)/EEG.srate;
                EEG.data = plot_rawdata; % channel X data (matrix)

                %{
                % filtering 함수
                i = length(rawdata);
                if i > 0
                    EEG = pop_eegfiltnew(EEG, app.LowcutoffEditField_2.Value, app.HighcutoffEditField_2.Value, [], 0, [], 0);

                    clear selected_channel
                    selected_channel = [find(strcmp(app.Ch1ListBox_2.Items, app.Ch1ListBox_2.Value))];
                    if app.Ch2CheckBox_2.Value == 1
                        %                     app.Ch2ListBox_2.Value
                        selected_channel = [selected_channel, find(strcmp(app.Ch2ListBox_2.Items, app.Ch2ListBox_2.Value))];
                    end
                    if app.Ch3CheckBox_2.Value == 1
                        %                     app.Ch3ListBox_2.Value
                        selected_channel = [selected_channel, find(strcmp(app.Ch3ListBox_2.Items, app.Ch3ListBox_2.Value))];
                    end
                    if app.Ch4CheckBox_2.Value == 1
                        %                     app.Ch4ListBox_2.Value
                        selected_channel = [selected_channel, find(strcmp(app.Ch4ListBox_2.Items, app.Ch4ListBox_2.Value))];
                    end

                    plot_rawdata_T = transpose(EEG.data); % data X channel (matrix)

                    % Time domain UIAxes
                    switch app.DisplayTdomainCheckBox_2.Value
                        case 1
                            plot_rawdata_T2 = (plot_rawdata_T-mean(plot_rawdata_T))*10;
                            selected_plot_rawdata_T = plot_rawdata_T2(:, selected_channel);
                            k = length(plot_rawdata);
                            for j = 1:1:size(selected_channel, 2)
                                selected_plot_rawdata_T(:,j) = selected_plot_rawdata_T(:,j) + (500-10*j)*3000;
                            end

                            if k < 2000
                                plot(app.T_domainUIAxes_2, selected_plot_rawdata_T)
                                xlim(app.T_domainUIAxes_2, [0 2000])
                                drawnow;
                            elseif k >= 2000
                                plot(app.T_domainUIAxes_2, selected_plot_rawdata_T(k-1999:k,:))
                                xlim(app.T_domainUIAxes_2, [0 2000])
                                drawnow;
                            end
                        case 0
                    end

                    % Frequency domain UIAxes
                    switch app.DisplayFdomainCheckBox_2.Value
                        case 1
                            if timesum(end) - timesum(1) >= preparation_time + (app.MI_MeasurementTimeSpinner_2.Value+app.RestingMeasurementTimeSpinner_2.Value)*fft_i+0.1
                                size_correction = 0;
                                if app.MI_MeasurementTimeSpinner_2.Value*EEG.srate > size(fft_rawdata_T(MI_events(fft_i)+1 : end, :),1)
                                    size_correction = app.MI_MeasurementTimeSpinner_2.Value*EEG.srate - size(fft_rawdata_T(MI_events(fft_i)+1 : end, :),1);
                                end
                                fftdata_all = fft(fft_rawdata_T(MI_events(fft_i)+1 : MI_events(fft_i)+app.MI_MeasurementTimeSpinner_2.Value*EEG.srate-size_correction, :));
                                %                         disp(size_correction)
                                %                         disp(size(fft_rawdata_T(MI_events(fft_i)+1 : MI_events(fft_i)+app.MI_MeasurementTimeSpinner.Value*EEG.srate-size_correction, :),1))
                                %                         disp(size(fft_rawdata_T(MI_events(fft_i)+1 : end, :),1))

                                N = app.MI_MeasurementTimeSpinner_2.Value*EEG.srate;
                                fftdata_all = 2*abs(fftdata_all)/N;
                                fftdata = fftdata_all(:, selected_channel);

                                n = 0:N-1;
                                f_axis = EEG.srate*n/N;
                                cutoff = ceil(N/2);
                                fftdata = fftdata(1:cutoff,:);
                                f_axis = f_axis(1:cutoff);

                                selected_fftdata_value = zeros(1, length(selected_channel));
                                for i = 1:length(selected_channel)
                                    selected_fftdata_value(i) = mean(fftdata((f_axis >= app.LowcutoffEditField_2.Value) & (f_axis <= app.HighcutoffEditField_2.Value),i));
                                end
                                xtick_labels = cell(1, length(selected_channel));
                                for i = 1:length(selected_channel)
                                    xtick_labels{i} = strcat('Ch', num2str(i));
                                end

                                bar(app.F_domainUIAxes_2, selected_fftdata_value, 'FaceColor',[.5 .5 .5], 'LineWidth',1);
                                xticklabels(app.F_domainUIAxes_2, xtick_labels);

                                for i = 1:length(selected_fftdata_value)
                                    text(app.F_domainUIAxes_2, i, selected_fftdata_value(i), num2str(selected_fftdata_value(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                                end

                                fft_i = fft_i + 1;
                            end
                        case 0
                    end
                end

                
                    % Stop recording
                    pause(10^-323)
                    if app.stopFlag == true
                        complete_record = "no";
                        break
                    end
                
            end
                %}
                % Stop recording
                pause(10^-323)
                if app.stopFlag == true
                    complete_record = "no";
                    break
                end
            end

            if complete_record == "yes"
                MI_time_interval = app.MI_MeasurementTimeSpinner_2.Value;
                y_test = ones(1, app.RepeatCountSpinner_2.Value);
                result = app.TotalResultTextArea.Value;
                accuracy = app.AccuracyEditField.Value;
                try
                    uisave({'result', 'accuracy', 'rawdata', 'y_test'},'')
                    %                     uisave({'rawdata', 'MI_events', 'MI_time_interval', 'y_test'},'')
                    %                     uisave({'result', 'accuracy'},'')
                catch
                    disp('Try again')
                end
            end

            app.stopFlag = false;
            %cla(app.T_domainUIAxes_2)
            %cla(app.F_domainUIAxes_2)
            %cla(app.ProtocolUIAxes)
            app.RestingSoundImage.Enable = "off";
            app.FistSoundImage.Enable = "off";
            app.PalmSoundImage.Enable = "off";
            app.RepeatCountEditField.Value = 0;
            app.TotalResultTextArea.Value = "";
            app.AccuracyEditField.Value = 0;
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.stopFlag = true;
            app.SecondEditField.Value = 0;
            app.MinuteEditField.Value = 0;

        end

        % Button pushed function: FIstButton
        function FIstButtonPushed(app, event)
            sound(app.sound_x1, app.sound_fs);

        end

        % Button pushed function: RestingButton
        function RestingButtonPushed(app, event)
            sound(app.sound_x2, app.sound_fs);

        end

        % Callback function: BandpowerButton, MI_MeasurementTimeSpinner, 
        % ...and 1 other component
        function BandpowerButtonValueChanged(app, event)
            if app.BandpowerButton.Value == 1
                app.BandpowerButton.Text = "=> Band power <=";
            elseif app.BandpowerButton.Value == 0 && app.ERDERSButton.Value == 1
                app.BandpowerButton.Text = "Band power";
            elseif app.BandpowerButton.Value == 0 && app.ERDERSButton.Value == 0
                app.BandpowerButton.Text = "Band power";

                app.ERDERSButton.Value = 1;
                app.ERDERSButton.Text = "=> ERD/ERS <=";
            end

        end

        % Value changed function: ERDERSButton
        function ERDERSButtonValueChanged(app, event)
            if app.ERDERSButton.Value == 1
                app.ERDERSButton.Text = "=> ERD/ERS <=";
            elseif app.ERDERSButton.Value == 0 && app.BandpowerButton.Value == 1
                app.ERDERSButton.Text = "ERD/ERS";
            elseif app.ERDERSButton.Value == 0 && app.BandpowerButton.Value == 0
                app.ERDERSButton.Text = "ERD/ERS";

                app.BandpowerButton.Value = 1;
                app.BandpowerButton.Text = "=> Band power <=";
            end

        end

        % Value changed function: FilterbankSpinner
        function FilterbankSpinnerValueChanged(app, event)
            app.LFBEditField_2.Enable ="off";app.LFBEditField_3.Enable ="off";app.LFBEditField_4.Enable ="off";

            app.LFBEditField_5.Enable ="off";app.LFBEditField_6.Enable ="off";app.LFBEditField_7.Enable ="off";
            app.LFBEditField_8.Enable ="off";app.LFBEditField_9.Enable ="off";app.LFBEditField_10.Enable ="off";
            app.HFBEditField_2.Enable ="off";app.HFBEditField_3.Enable ="off";app.HFBEditField_4.Enable ="off";
            app.HFBEditField_5.Enable ="off";app.HFBEditField_6.Enable ="off";app.HFBEditField_7.Enable ="off";
            app.HFBEditField_8.Enable ="off";app.HFBEditField_9.Enable ="off";app.HFBEditField_10.Enable ="off";

            if app.FilterbankSpinner.Value == 2
                app.LFBEditField_2.Enable ="on";
                app.HFBEditField_2.Enable ="on";
            elseif app.FilterbankSpinner.Value == 3
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";
            elseif app.FilterbankSpinner.Value == 4
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";
            elseif app.FilterbankSpinner.Value == 5
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";app.LFBEditField_5.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";app.HFBEditField_5.Enable ="on";
            elseif app.FilterbankSpinner.Value == 6
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";app.LFBEditField_5.Enable ="on";app.LFBEditField_6.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";app.HFBEditField_5.Enable ="on";app.HFBEditField_6.Enable ="on";
            elseif app.FilterbankSpinner.Value == 7
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";
                app.LFBEditField_5.Enable ="on";app.LFBEditField_6.Enable ="on";app.LFBEditField_7.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";
                app.HFBEditField_5.Enable ="on";app.HFBEditField_6.Enable ="on";app.HFBEditField_7.Enable ="on";
            elseif app.FilterbankSpinner.Value == 8
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";app.LFBEditField_5.Enable ="on";
                app.LFBEditField_6.Enable ="on";app.LFBEditField_7.Enable ="on";app.LFBEditField_8.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";app.HFBEditField_5.Enable ="on";
                app.HFBEditField_6.Enable ="on";app.HFBEditField_7.Enable ="on";app.HFBEditField_8.Enable ="on";
            elseif app.FilterbankSpinner.Value == 9
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";app.LFBEditField_5.Enable ="on";
                app.LFBEditField_6.Enable ="on";app.LFBEditField_7.Enable ="on";app.LFBEditField_8.Enable ="on";app.LFBEditField_9.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";app.HFBEditField_5.Enable ="on";
                app.HFBEditField_6.Enable ="on";app.HFBEditField_7.Enable ="on";app.HFBEditField_8.Enable ="on";app.HFBEditField_9.Enable ="on";
            elseif app.FilterbankSpinner.Value == 10
                app.LFBEditField_2.Enable ="on";app.LFBEditField_3.Enable ="on";app.LFBEditField_4.Enable ="on";app.LFBEditField_5.Enable ="on";
                app.LFBEditField_6.Enable ="on";app.LFBEditField_7.Enable ="on";app.LFBEditField_8.Enable ="on";app.LFBEditField_9.Enable ="on";
                app.LFBEditField_10.Enable ="on";
                app.HFBEditField_2.Enable ="on";app.HFBEditField_3.Enable ="on";app.HFBEditField_4.Enable ="on";app.HFBEditField_5.Enable ="on";
                app.HFBEditField_6.Enable ="on";app.HFBEditField_7.Enable ="on";app.HFBEditField_8.Enable ="on";app.HFBEditField_9.Enable ="on";
                app.HFBEditField_10.Enable ="on";
            end

        end

        % Value changed function: RangeSpinner
        function RangeSpinnerValueChanged(app, event)
            app.OverlabSpinner.Limits = [app.OverlabSpinner.Limits(1) app.RangeSpinner.Value];
            if app.RangeCheckBox.Value == 1
                app.HFBEditField_1.Value = app.LFBEditField_1.Value+app.RangeSpinner.Value;
                app.LFBEditField_2.Value = app.HFBEditField_1.Value-app.OverlabSpinner.Value; app.HFBEditField_2.Value = app.LFBEditField_2.Value+app.RangeSpinner.Value;
                app.LFBEditField_3.Value = app.HFBEditField_2.Value-app.OverlabSpinner.Value; app.HFBEditField_3.Value = app.LFBEditField_3.Value+app.RangeSpinner.Value;
                app.LFBEditField_4.Value = app.HFBEditField_3.Value-app.OverlabSpinner.Value; app.HFBEditField_4.Value = app.LFBEditField_4.Value+app.RangeSpinner.Value;
                app.LFBEditField_5.Value = app.HFBEditField_4.Value-app.OverlabSpinner.Value; app.HFBEditField_5.Value = app.LFBEditField_5.Value+app.RangeSpinner.Value;
                app.LFBEditField_6.Value = app.HFBEditField_5.Value-app.OverlabSpinner.Value; app.HFBEditField_6.Value = app.LFBEditField_6.Value+app.RangeSpinner.Value;
                app.LFBEditField_7.Value = app.HFBEditField_6.Value-app.OverlabSpinner.Value; app.HFBEditField_7.Value = app.LFBEditField_7.Value+app.RangeSpinner.Value;
                app.LFBEditField_8.Value = app.HFBEditField_7.Value-app.OverlabSpinner.Value; app.HFBEditField_8.Value = app.LFBEditField_8.Value+app.RangeSpinner.Value;
                app.LFBEditField_9.Value = app.HFBEditField_8.Value-app.OverlabSpinner.Value; app.HFBEditField_9.Value = app.LFBEditField_9.Value+app.RangeSpinner.Value;
                app.LFBEditField_10.Value= app.HFBEditField_9.Value-app.OverlabSpinner.Value; app.HFBEditField_10.Value= app.LFBEditField_10.Value+app.RangeSpinner.Value;
            end

        end

        % Value changed function: OverlabSpinner
        function OverlabSpinnerValueChanged(app, event)
            app.OverlabSpinner.Limits = [app.OverlabSpinner.Limits(1) app.RangeSpinner.Value];
            if app.OverlapCheckBox.Value == 1
                app.HFBEditField_1.Value = app.LFBEditField_1.Value+app.RangeSpinner.Value;
                app.LFBEditField_2.Value = app.HFBEditField_1.Value-app.OverlabSpinner.Value; app.HFBEditField_2.Value = app.LFBEditField_2.Value+app.RangeSpinner.Value;
                app.LFBEditField_3.Value = app.HFBEditField_2.Value-app.OverlabSpinner.Value; app.HFBEditField_3.Value = app.LFBEditField_3.Value+app.RangeSpinner.Value;
                app.LFBEditField_4.Value = app.HFBEditField_3.Value-app.OverlabSpinner.Value; app.HFBEditField_4.Value = app.LFBEditField_4.Value+app.RangeSpinner.Value;
                app.LFBEditField_5.Value = app.HFBEditField_4.Value-app.OverlabSpinner.Value; app.HFBEditField_5.Value = app.LFBEditField_5.Value+app.RangeSpinner.Value;
                app.LFBEditField_6.Value = app.HFBEditField_5.Value-app.OverlabSpinner.Value; app.HFBEditField_6.Value = app.LFBEditField_6.Value+app.RangeSpinner.Value;
                app.LFBEditField_7.Value = app.HFBEditField_6.Value-app.OverlabSpinner.Value; app.HFBEditField_7.Value = app.LFBEditField_7.Value+app.RangeSpinner.Value;
                app.LFBEditField_8.Value = app.HFBEditField_7.Value-app.OverlabSpinner.Value; app.HFBEditField_8.Value = app.LFBEditField_8.Value+app.RangeSpinner.Value;
                app.LFBEditField_9.Value = app.HFBEditField_8.Value-app.OverlabSpinner.Value; app.HFBEditField_9.Value = app.LFBEditField_9.Value+app.RangeSpinner.Value;
                app.LFBEditField_10.Value= app.HFBEditField_9.Value-app.OverlabSpinner.Value; app.HFBEditField_10.Value= app.LFBEditField_10.Value+app.RangeSpinner.Value;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProtocolUIAxes = uiaxes(app.UIFigure);
            title(app.ProtocolUIAxes, 'SSVEP Signal acquisition protocol')
            zlabel(app.ProtocolUIAxes, 'Z')
            app.ProtocolUIAxes.XTick = [];
            app.ProtocolUIAxes.YTick = [];
            app.SignalpreprocessingTraningclassificationmodelPanel = uipanel(app.UIFigure);
            app.SignalpreprocessingTraningclassificationmodelPanel.Title = 'Signal preprocessing & Traning classification model';
            app.LoadtraningdataButton = uibutton(app.SignalpreprocessingTraningclassificationmodelPanel, 'push');
            app.LoadtraningdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtraningdataButtonPushed, true);
            app.LoadtraningdataButton.Text = 'Load traning data';
            app.LocationFileLabel = uilabel(app.SignalpreprocessingTraningclassificationmodelPanel);
            app.LocationFileLabel.Text = '';
            app.TraningmodelButton = uibutton(app.SignalpreprocessingTraningclassificationmodelPanel, 'push');
            app.TraningmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TraningmodelButtonPushed, true);
            app.TraningmodelButton.Text = 'Traning model';
            app.Tree = uitree(app.SignalpreprocessingTraningclassificationmodelPanel, 'checkbox');
            app.ChannelNode = uitreenode(app.Tree);
            app.ChannelNode.Text = 'Channel';
            app.F7Node = uitreenode(app.ChannelNode);
            app.F7Node.Text = 'F7';
            app.FP1Node = uitreenode(app.ChannelNode);
            app.FP1Node.Text = 'FP1';
            app.FP2Node = uitreenode(app.ChannelNode);
            app.FP2Node.Text = 'FP2';
            app.F8Node = uitreenode(app.ChannelNode);
            app.F8Node.Text = 'F8';
            app.F3Node = uitreenode(app.ChannelNode);
            app.F3Node.Text = 'F3';
            app.FZNode = uitreenode(app.ChannelNode);
            app.FZNode.Text = 'FZ';
            app.F4Node = uitreenode(app.ChannelNode);
            app.F4Node.Text = 'F4';
            app.C3Node = uitreenode(app.ChannelNode);
            app.C3Node.Text = 'C3';
            app.CZNode = uitreenode(app.ChannelNode);
            app.CZNode.Text = 'CZ';
            app.P8Node = uitreenode(app.ChannelNode);
            app.P8Node.Text = 'P8';
            app.P7Node = uitreenode(app.ChannelNode);
            app.P7Node.Text = 'P7';
            app.PZNode = uitreenode(app.ChannelNode);
            app.PZNode.Text = 'PZ';
            app.P4Node = uitreenode(app.ChannelNode);
            app.P4Node.Text = 'P4';
            app.T3Node = uitreenode(app.ChannelNode);
            app.T3Node.Text = 'T3';
            app.P3Node = uitreenode(app.ChannelNode);
            app.P3Node.Text = 'P3';
            app.O1Node = uitreenode(app.ChannelNode);
            app.O1Node.Text = 'O1';
            app.O2Node = uitreenode(app.ChannelNode);
            app.O2Node.Text = 'O2';
            app.C4Node = uitreenode(app.ChannelNode);
            app.C4Node.Text = 'C4';
            app.T4Node = uitreenode(app.ChannelNode);
            app.T4Node.Text = 'T4';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.CZNode, app.PZNode, app.O1Node, app.O2Node];
            app.LoaddataLabel = uilabel(app.SignalpreprocessingTraningclassificationmodelPanel);
            app.LoaddataLabel.Text = '==> Load data <==';
            app.TabGroup2 = uitabgroup(app.SignalpreprocessingTraningclassificationmodelPanel);
            app.PreprocessingTab = uitab(app.TabGroup2);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.TabGroup = uitabgroup(app.PreprocessingTab);
            app.WindowrangeTab = uitab(app.TabGroup);
            app.WindowrangeTab.Title = 'Window range';
            app.WindowLabel = uilabel(app.WindowrangeTab);
            app.WindowLabel.Text = '~';
            app.WindowrangeSpinner = uispinner(app.WindowrangeTab);
            app.WindowrangeSpinner.Step = 0.1;
            app.WindowrangeSpinner.Limits = [0 Inf];
            app.WindowrangeSpinner.ValueDisplayFormat = '%.1f';
            app.WindowrangeSpinner_2 = uispinner(app.WindowrangeTab);
            app.WindowrangeSpinner_2.Step = 0.1;
            app.WindowrangeSpinner_2.Limits = [0 Inf];
            app.WindowrangeSpinner_2.ValueDisplayFormat = '%.1f';
            app.WindowrangeSpinner_2.Value = 4;
            app.SegmentTab = uitab(app.TabGroup);
            app.SegmentTab.Title = 'Segment';
            app.Label_5 = uilabel(app.SegmentTab);
            app.Label_5.Text = '_________';
            app.Label_6 = uilabel(app.SegmentTab);
            app.Label_6.Text = '_________';
            app.WindowSplitSpinner = uispinner(app.SegmentTab);
            app.WindowSplitSpinner.Limits = [1 4];
            app.WindowSplitSpinner.Value = 1;
            app.DownsamplingSpinnerLabel = uilabel(app.PreprocessingTab);
            app.DownsamplingSpinnerLabel.Text = 'Down sampling';
            app.DownsamplingSpinner = uispinner(app.PreprocessingTab);
            app.DownsamplingSpinner.Step = 5;
            app.DownsamplingSpinner.Limits = [0 500];
            app.DownsamplingSpinner.ValueDisplayFormat = '%.0f';
            app.DownsamplingSpinner.Value = 256;
            app.CARLabel = uilabel(app.PreprocessingTab);
            app.CARLabel.Text = 'Common average reference (CAR)';
            app.CARCheckBox = uicheckbox(app.PreprocessingTab);
            app.CARCheckBox.Text = '';
            app.Label_3 = uilabel(app.PreprocessingTab);
            app.Label_3.Text = '____________';
            app.Label_4 = uilabel(app.PreprocessingTab);
            app.Label_4.Text = '____________';
            app.BandPassFilterLabel = uilabel(app.PreprocessingTab);
            app.BandPassFilterLabel.Text = 'Band pass filter';
            app.HighcutoffEditField_3Label = uilabel(app.PreprocessingTab);
            app.HighcutoffEditField_3Label.Text = 'Highcutoff';
            app.HighcutoffEditField_3 = uieditfield(app.PreprocessingTab, 'numeric');
            app.HighcutoffEditField_3.Limits = [0 100];
            app.HighcutoffEditField_3.Value = 30;
            app.LowcutoffEditField_3Label = uilabel(app.PreprocessingTab);
            app.LowcutoffEditField_3Label.Text = 'Lowcutoff';
            app.LowcutoffEditField_3 = uieditfield(app.PreprocessingTab, 'numeric');
            app.LowcutoffEditField_3.Limits = [0 100];
            app.LowcutoffEditField_3.Value = 1;
            app.L_HLabel_3 = uilabel(app.PreprocessingTab);
            app.L_HLabel_3.Text = '~';
            app.FeatureselectionTab = uitab(app.TabGroup2);
            app.FeatureselectionTab.Title = 'Feature selection';
            app.FilterbankSpinnerLabel = uilabel(app.FeatureselectionTab);
            app.FilterbankSpinnerLabel.Text = 'Filter bank';
            app.FilterbankSpinner = uispinner(app.FeatureselectionTab);
            app.FilterbankSpinner.Limits = [1 10];
            app.FilterbankSpinner.ValueDisplayFormat = '%.0f';
            app.FilterbankSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterbankSpinnerValueChanged, true);
            app.FilterbankSpinner.Value = 1;
            app.HFBEditField_2 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_2.Limits = [0 100];
            app.HFBEditField_2.Enable = 'off';
            app.HFBEditField_2.Value = 30;
            app.FBLabel_2 = uilabel(app.FeatureselectionTab);
            app.FBLabel_2.Text = '~';
            app.LFBEditField_2 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_2.Limits = [0 100];
            app.LFBEditField_2.Enable = 'off';
            app.LFBEditField_2.Value = 1;
            app.HFBEditField_3 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_3.Limits = [0 100];
            app.HFBEditField_3.Enable = 'off';
            app.HFBEditField_3.Value = 30;
            app.FBLabel_3 = uilabel(app.FeatureselectionTab);
            app.FBLabel_3.Text = '~';
            app.LFBEditField_3 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_3.Limits = [0 100];
            app.LFBEditField_3.Enable = 'off';
            app.LFBEditField_3.Value = 1;
            app.HFBEditField_4 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_4.Limits = [0 100];
            app.HFBEditField_4.Enable = 'off';
            app.HFBEditField_4.Value = 30;
            app.FBLabel_4 = uilabel(app.FeatureselectionTab);
            app.FBLabel_4.Text = '~';
            app.LFBEditField_4 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_4.Limits = [0 100];
            app.LFBEditField_4.Enable = 'off';
            app.LFBEditField_4.Value = 1;
            app.HFBEditField_5 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_5.Limits = [0 100];
            app.HFBEditField_5.Enable = 'off';
            app.HFBEditField_5.Value = 30;
            app.FBLabel_5 = uilabel(app.FeatureselectionTab);
            app.FBLabel_5.Text = '~';
            app.LFBEditField_5 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_5.Limits = [0 100];
            app.LFBEditField_5.Enable = 'off';
            app.LFBEditField_5.Value = 1;
            app.FBLabel_1 = uilabel(app.FeatureselectionTab);
            app.FBLabel_1.Text = '~';
            app.HFBEditField_1 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_1.Limits = [0 100];
            app.HFBEditField_1.Value = 30;
            app.LFBEditField_1 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_1.Limits = [0 100];
            app.LFBEditField_1.Value = 1;
            app.HFBEditField_6 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_6.Limits = [0 100];
            app.HFBEditField_6.Enable = 'off';
            app.HFBEditField_6.Value = 30;
            app.FBLabel_6 = uilabel(app.FeatureselectionTab);
            app.FBLabel_6.Text = '~';
            app.LFBEditField_6 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_6.Limits = [0 100];
            app.LFBEditField_6.Enable = 'off';
            app.LFBEditField_6.Value = 1;
            app.HFBEditField_7 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_7.Limits = [0 100];
            app.HFBEditField_7.Enable = 'off';
            app.HFBEditField_7.Value = 30;
            app.FBLabel_7 = uilabel(app.FeatureselectionTab);
            app.FBLabel_7.Text = '~';
            app.LFBEditField_7 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_7.Limits = [0 100];
            app.LFBEditField_7.Enable = 'off';
            app.LFBEditField_7.Value = 1;
            app.HFBEditField_8 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_8.Limits = [0 100];
            app.HFBEditField_8.Enable = 'off';
            app.HFBEditField_8.Value = 30;
            app.FBLabel_8 = uilabel(app.FeatureselectionTab);
            app.FBLabel_8.Text = '~';
            app.LFBEditField_8 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_8.Limits = [0 100];
            app.LFBEditField_8.Enable = 'off';
            app.LFBEditField_8.Value = 1;
            app.HFBEditField_9 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_9.Limits = [0 100];
            app.HFBEditField_9.Enable = 'off';
            app.HFBEditField_9.Value = 30;
            app.FBLabel_9 = uilabel(app.FeatureselectionTab);
            app.FBLabel_9.Text = '~';
            app.LFBEditField_9 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_9.Limits = [0 100];
            app.LFBEditField_9.Enable = 'off';
            app.LFBEditField_9.Value = 1;
            app.HFBEditField_10 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.HFBEditField_10.Limits = [0 100];
            app.HFBEditField_10.Enable = 'off';
            app.HFBEditField_10.Value = 30;
            app.FBLabel_10 = uilabel(app.FeatureselectionTab);
            app.FBLabel_10.Text = '~';
            app.LFBEditField_10 = uieditfield(app.FeatureselectionTab, 'numeric');
            app.LFBEditField_10.Limits = [0 100];
            app.LFBEditField_10.Enable = 'off';
            app.LFBEditField_10.Value = 1;
            app.RangeCheckBox = uicheckbox(app.FeatureselectionTab);
            app.RangeCheckBox.Text = 'Range';
            app.OverlapCheckBox = uicheckbox(app.FeatureselectionTab);
            app.OverlapCheckBox.Text = 'Overlap';
            app.RangeSpinner = uispinner(app.FeatureselectionTab);
            app.RangeSpinner.Limits = [1 10];
            app.RangeSpinner.ValueDisplayFormat = '%.0f';
            app.RangeSpinner.ValueChangedFcn = createCallbackFcn(app, @RangeSpinnerValueChanged, true);
            app.RangeSpinner.Value = 1;
            app.OverlabSpinner = uispinner(app.FeatureselectionTab);
            app.OverlabSpinner.Limits = [-5 1];
            app.OverlabSpinner.ValueDisplayFormat = '%.0f';
            app.OverlabSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlabSpinnerValueChanged, true);
            app.BandpowerButton = uibutton(app.FeatureselectionTab, 'state');
            app.BandpowerButton.ValueChangedFcn = createCallbackFcn(app, @BandpowerButtonValueChanged, true);
            app.BandpowerButton.Text = '=> Band power <=';
            app.BandpowerButton.Value = true;
            app.ERDERSButton = uibutton(app.FeatureselectionTab, 'state');
            app.ERDERSButton.ValueChangedFcn = createCallbackFcn(app, @ERDERSButtonValueChanged, true);
            app.ERDERSButton.Text = '=> ERD/ERS <=';
            app.ERDERSButton.Value = true;
            app.ResultPanel = uipanel(app.UIFigure);
            app.AccuracyLabel = uilabel(app.ResultPanel);
            app.AccuracyLabel.Text = 'Accuracy (%)';
            app.AccuracyEditField = uieditfield(app.ResultPanel, 'numeric');
            app.AccuracyEditField.ValueDisplayFormat = '%.4f';
            app.AccuracyEditField.Editable = 'off';
            app.TotalResultTextAreaLabel = uilabel(app.ResultPanel);
            app.TotalResultTextAreaLabel.Text = 'Total Result';
            app.TotalResultTextArea = uitextarea(app.ResultPanel);
            app.TotalResultTextArea.Editable = 'off';
            app.FistSoundImage = uiimage(app.UIFigure);
            app.FistSoundImage.Enable = 'off';
            app.FistSoundImage.ImageSource = fullfile(pathToMLAPP, 'Fist_sound.png');
            app.RestingSoundImage = uiimage(app.UIFigure);
            app.RestingSoundImage.Enable = 'off';
            app.RestingSoundImage.ImageSource = fullfile(pathToMLAPP, 'Resting_sound.png');
            app.FIstButton = uibutton(app.UIFigure, 'push');
            app.FIstButton.ButtonPushedFcn = createCallbackFcn(app, @FIstButtonPushed, true);
            app.FIstButton.Text = 'Fist';
            app.RestingButton = uibutton(app.UIFigure, 'push');
            app.RestingButton.ButtonPushedFcn = createCallbackFcn(app, @RestingButtonPushed, true);
            app.RestingButton.Text = 'Rest';
            app.PalmSoundImage = uiimage(app.UIFigure);
            app.PalmSoundImage.Enable = 'off';
            app.PalmSoundImage.ImageSource = fullfile(pathToMLAPP, 'Palm_sound.png');
            app.PalmButton = uibutton(app.UIFigure, 'push');
            app.PalmButton.Text = 'Palm';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.TrainTab = uitab(app.TabGroup3);
            app.TrainTab.Title = 'Train';
            app.TrainingsessionPanel = uipanel(app.TrainTab);
            app.TrainingsessionPanel.Title = ' Training session';
            app.TrainingRecordButton = uibutton(app.TrainingsessionPanel, 'push');
            app.TrainingRecordButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingRecordButtonPushed, true);
            app.TrainingRecordButton.Text = 'Record';
            app.StopButton = uibutton(app.TrainingsessionPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TrainingsessionTabGroup = uitabgroup(app.TrainingsessionPanel);
            app.SignalacquisitionprotocolsettingDisplayuiaxesTab = uitab(app.TrainingsessionTabGroup);
            app.SignalacquisitionprotocolsettingDisplayuiaxesTab.Title = 'Signal acquisition protocol setting & Display uiaxes';
            app.Panel_3 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab);
            app.MI_MeasurementTimeSpinner = uispinner(app.Panel_3);
            app.MI_MeasurementTimeSpinner.ValueChangingFcn = createCallbackFcn(app, @BandpowerButtonValueChanged, true);
            app.MI_MeasurementTimeSpinner.Limits = [0 10];
            app.MI_MeasurementTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @MI_MeasurementTimeSpinnerValueChanged, true);
            app.MI_MeasurementTimeSpinner.Value = 4;
            app.MI_MeasurementTimeLabel = uilabel(app.Panel_3);
            app.MI_MeasurementTimeLabel.Text = 'Measurement time of motor imagery (S)';
            app.Panel_4 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab);
            app.RestingMeasurementTimeSpinner = uispinner(app.Panel_4);
            app.RestingMeasurementTimeSpinner.Limits = [0 10];
            app.RestingMeasurementTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @RestingMeasurementTimeSpinnerValueChanged, true);
            app.RestingMeasurementTimeSpinner.Value = 4;
            app.RestingMeasurementTimeLabel = uilabel(app.Panel_4);
            app.RestingMeasurementTimeLabel.Text = 'Measurement time of resting (S)';
            app.Panel_5 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab);
            app.RepeatCountSpinner = uispinner(app.Panel_5);
            app.RepeatCountSpinner.Limits = [0 100];
            app.RepeatCountSpinner.Value = 2;
            app.RepeatCountLabel = uilabel(app.Panel_5);
            app.RepeatCountLabel.Text = 'Repeat count';
            app.Panel_10 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab);
            app.FistsoundCheckBox = uicheckbox(app.Panel_10);
            app.FistsoundCheckBox.Text = 'Fist sound';
            app.FistsoundCheckBox.Value = true;
            app.RestingsoundCheckBox = uicheckbox(app.Panel_10);
            app.RestingsoundCheckBox.Text = 'Resting sound';
            app.RestingsoundCheckBox.Value = true;
            app.PalmsoundCheckBox = uicheckbox(app.Panel_10);
            app.PalmsoundCheckBox.Text = 'Palm sound';
            app.PalmsoundCheckBox.Value = true;
            app.TimePanel = uipanel(app.TrainingsessionPanel);
            app.TimePanel.Title = 'Time';
            app.MinuteLabel = uilabel(app.TimePanel);
            app.MinuteLabel.Text = 'Minute:';
            app.MinuteEditField = uieditfield(app.TimePanel, 'numeric');
            app.MinuteEditField.Limits = [0 Inf];
            app.MinuteEditField.Editable = 'off';
            app.SecondLabel = uilabel(app.TimePanel);
            app.SecondLabel.Text = 'Second:';
            app.SecondEditField = uieditfield(app.TimePanel, 'numeric');
            app.SecondEditField.Limits = [0 Inf];
            app.SecondEditField.Editable = 'off';
            app.SetpathButton = uibutton(app.TrainingsessionPanel, 'push');
            app.SetpathButton.ButtonPushedFcn = createCallbackFcn(app, @SetpathButtonPushed, true);
            app.SetpathButton.Text = 'Set path';
            app.TestTab = uitab(app.TabGroup3);
            app.TestTab.Title = 'Test';
            app.TestsessionPanel = uipanel(app.TestTab);
            app.TestsessionPanel.Title = ' Test session';
            app.TestsessionTabGroup = uitabgroup(app.TestsessionPanel);
            app.SignalacquisitionprotocolsettingDisplayuiaxesTab_2 = uitab(app.TestsessionTabGroup);
            app.SignalacquisitionprotocolsettingDisplayuiaxesTab_2.Title = 'Signal acquisition protocol setting & Display uiaxes';
            app.Panel_7 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab_2);
            app.MI_MeasurementTimeSpinner_2 = uispinner(app.Panel_7);
            app.MI_MeasurementTimeSpinner_2.Limits = [0 10];
            app.MI_MeasurementTimeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @BandpowerButtonValueChanged, true);
            app.MI_MeasurementTimeSpinner_2.Enable = 'off';
            app.MI_MeasurementTimeSpinner_2.Value = 4;
            app.MeasurementtimeofmotorimagerySLabel_2 = uilabel(app.Panel_7);
            app.MeasurementtimeofmotorimagerySLabel_2.Text = 'Measurement time of motor imagery (S)';
            app.Panel_8 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab_2);
            app.RestingMeasurementTimeSpinner_2 = uispinner(app.Panel_8);
            app.RestingMeasurementTimeSpinner_2.Limits = [0 10];
            app.RestingMeasurementTimeSpinner_2.Value = 4;
            app.TargetsizeLabel_4 = uilabel(app.Panel_8);
            app.TargetsizeLabel_4.Text = 'Measurement time of resting (S)';
            app.Panel_9 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab_2);
            app.RepeatCountSpinner_2 = uispinner(app.Panel_9);
            app.RepeatCountSpinner_2.Limits = [0 100];
            app.RepeatCountSpinner_2.Value = 2;
            app.TargetsizeLabel_5 = uilabel(app.Panel_9);
            app.TargetsizeLabel_5.Text = 'Repeat count';
            app.Panel_11 = uipanel(app.SignalacquisitionprotocolsettingDisplayuiaxesTab_2);
            app.FistsoundCheckBox_2 = uicheckbox(app.Panel_11);
            app.FistsoundCheckBox_2.Text = 'Fist sound';
            app.FistsoundCheckBox_2.Value = true;
            app.RestingsoundCheckBox_2 = uicheckbox(app.Panel_11);
            app.RestingsoundCheckBox_2.Text = 'Resting sound';
            app.RestingsoundCheckBox_2.Value = true;
            app.PalmsoundCheckBox_2 = uicheckbox(app.Panel_11);
            app.PalmsoundCheckBox_2.Text = 'Palm sound';
            app.PalmsoundCheckBox_2.Value = true;
            app.TimePanel_2 = uipanel(app.TestsessionPanel);
            app.TimePanel_2.Title = 'Time';
            app.MinuteLabel_2 = uilabel(app.TimePanel_2);
            app.MinuteLabel_2.Text = 'Minute:';
            app.MinuteEditField_2 = uieditfield(app.TimePanel_2, 'numeric');
            app.MinuteEditField_2.Limits = [0 Inf];
            app.MinuteEditField_2.Editable = 'off';
            app.SecondLabel_2 = uilabel(app.TimePanel_2);
            app.SecondLabel_2.Text = 'Second:';
            app.SecondEditField_2 = uieditfield(app.TimePanel_2, 'numeric');
            app.SecondEditField_2.Limits = [0 Inf];
            app.SecondEditField_2.Editable = 'off';
            app.RecordButton_2 = uibutton(app.TestsessionPanel, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_2Pushed, true);
            app.RecordButton_2.Text = 'Record';
            app.StopButton_2 = uibutton(app.TestsessionPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.SetpathButton_2 = uibutton(app.TestsessionPanel, 'push');
            app.SetpathButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetpathButton_2Pushed, true);
            app.SetpathButton_2.Text = 'Set path';
            app.RepeatCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.RepeatCountEditField.Editable = 'off';
            app.ResultPanel_2 = uipanel(app.UIFigure);
            app.AccuracyEditField_2Label = uilabel(app.ResultPanel_2);
            app.AccuracyEditField_2Label.Text = 'Accuracy (%)';
            app.AccuracyEditField_Fist = uieditfield(app.ResultPanel_2, 'numeric');
            app.AccuracyEditField_Fist.ValueDisplayFormat = '%.4f';
            app.AccuracyEditField_Fist.Editable = 'off';
            app.FistResultTextAreaLabel = uilabel(app.ResultPanel_2);
            app.FistResultTextAreaLabel.Text = 'Fist Result';
            app.FistResultTextArea = uitextarea(app.ResultPanel_2);
            app.FistResultTextArea.Editable = 'off';
            app.ResultPanel_3 = uipanel(app.UIFigure);
            app.AccuracyEditField_3Label = uilabel(app.ResultPanel_3);
            app.AccuracyEditField_3Label.Text = 'Accuracy (%)';
            app.AccuracyEditField_Palm = uieditfield(app.ResultPanel_3, 'numeric');
            app.AccuracyEditField_Palm.ValueDisplayFormat = '%.4f';
            app.AccuracyEditField_Palm.Editable = 'off';
            app.PalmResultTextAreaLabel = uilabel(app.ResultPanel_3);
            app.PalmResultTextAreaLabel.Text = 'Palm Result';
            app.PalmResultTextArea = uitextarea(app.ResultPanel_3);
            app.PalmResultTextArea.Editable = 'off';
            app.Panel_12 = uipanel(app.UIFigure);
            app.SSVEPLabel = uilabel(app.Panel_12);
            app.SSVEPLabel.Text = 'SSVEP 주파수 설정';
            app.HZLabel = uilabel(app.Panel_12);
            app.HZLabel.Text = 'HZ';
            app.HZLabel_2 = uilabel(app.Panel_12);
            app.HZLabel_2.Text = 'HZ';
            app.FistSpinnerLabel = uilabel(app.Panel_12);
            app.FistSpinnerLabel.Text = 'Fist';
            app.FistSpinner = uispinner(app.Panel_12);
            app.PalmSpinnerLabel = uilabel(app.Panel_12);
            app.PalmSpinnerLabel.Text = 'Palm';
            app.PalmSpinner = uispinner(app.Panel_12);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSVEP_session

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end